classdef ProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        LogsheetPathEditFieldLabel     matlab.ui.control.Label
        LogsheetPathEditField          matlab.ui.control.EditField
        ProjectNameEditFieldLabel      matlab.ui.control.Label
        ProjectNameEditField           matlab.ui.control.EditField
        ProjectPathEditFieldLabel      matlab.ui.control.Label
        ProjectPathEditField           matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        RedoCheckBox                   matlab.ui.control.CheckBox
        AddDataTypesCheckBox           matlab.ui.control.CheckBox
        UpdateMetadataOnlyCheckBox     matlab.ui.control.CheckBox
        ReloadExistingDataCheckBox     matlab.ui.control.CheckBox
        OpenimportSettingsButton       matlab.ui.control.Button
        OpenspecifyTrials_importProjectButton  matlab.ui.control.Button
        ProcessTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        FunctionNamesLabel             matlab.ui.control.Label
        FunctionNamesTextArea          matlab.ui.control.TextArea
        FunctionGroupEditFieldLabel    matlab.ui.control.Label
        FunctionGroupEditField         matlab.ui.control.NumericEditField
        OpenspecifyTrialsButton        matlab.ui.control.Button
        AddFunctionstoGroupSavetoFileButton  matlab.ui.control.Button
        ClickoutsideboxwhendoneLabel   matlab.ui.control.Label
        LoadFunctionNamesinGroupFromFileButton  matlab.ui.control.Button
        NewProcessingFunctionTrialLevelButton  matlab.ui.control.Button
        NewProcessingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewProcessingFunctionProjectLevelButton  matlab.ui.control.Button
        RunTab                         matlab.ui.container.Tab
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        RedoFcnLabel                   matlab.ui.control.Label
        CheckBox_1                     matlab.ui.control.CheckBox
        RunGroupButton                 matlab.ui.control.Button
        SaveArgstoFileButton           matlab.ui.control.Button
        RunAllButton                   matlab.ui.control.Button
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_10                    matlab.ui.control.CheckBox
        FunctionNamesTextArea_2Label   matlab.ui.control.Label
        FunctionNamesTextArea_2        matlab.ui.control.TextArea
        FunctionArgsTextAreaLabel      matlab.ui.control.Label
        FunctionArgsTextArea           matlab.ui.control.TextArea
        DropDown2                      matlab.ui.control.DropDown
        OpenFunctionButton             matlab.ui.control.Button
        PlotTab                        matlab.ui.container.Tab
        MultipleTrialsCheckBox         matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SaveFigCheckBox                matlab.ui.control.CheckBox
        SavePNGCheckBox                matlab.ui.control.CheckBox
        SavetransparentPNGCheckBox     matlab.ui.control.CheckBox
        NewPlottingFunctionTrialLevelButton  matlab.ui.control.Button
        RootSaveFolderEditFieldLabel   matlab.ui.control.Label
        RootSaveFolderEditField        matlab.ui.control.EditField
        CurrentSaveSubfolderEditFieldLabel  matlab.ui.control.Label
        CurrentSaveSubfolderEditField  matlab.ui.control.EditField
        SaveSVGCheckBox                matlab.ui.control.CheckBox
        PlottingFunctionNameEditFieldLabel  matlab.ui.control.Label
        PlottingFunctionNameEditField  matlab.ui.control.EditField
        NewPlottingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewPlottingFunctionProjectLevelButton  matlab.ui.control.Button
        StatisticsTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CurrGroupFcnNames % Function names for one individual set of functions.
        FcnSet % Number identifying one set of functions
        allFcnNames % Names of all functions to run (across all groups)
        projectName % Project name from projectStruct.Info.ProjectName
        procFunc % Processing function names
        groupNum % Run tab in Process current selection.
        redoVal % Stores the value of the Redo checkboxes when switching between groups or tabs.
        numRunTabs % Number of times switched over to the Run tab. Used for Redo checkbox values.
        groupArgs % The function arguments split by group
        plotFuncName % Name of the current plotting function to run
        PlotMult % Checkbox to plot multiple trials on one plot
        SaveFIG % Checkbox to save plot as .fig
        SavePNG % Checkbox to save plot as .png
        SaveTransPNG % Checkbox to save plot as transparent .png
        SaveSVG % Checkbox to save plot as .svg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numRunTabs=0; % Initialize the number of times on the run tab to be 0.
            app.RedoCheckBox.Value=0; % Initialize Redo to be unchecked.
            evalin('base','projectStruct.Info.Flags.Redo=0;');
            app.AddDataTypesCheckBox.Value=0; % AddDataTypes begins unchecked.
            evalin('base','projectStruct.Info.Flags.AddDataTypes=0;');
            app.UpdateMetadataOnlyCheckBox.Value=0; % UpdateMetadata begins unchecked.
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=0;');
            app.ReloadExistingDataCheckBox.Value=0; % ReloadExistingData begins unchecked.
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=0;');
            app.MultipleTrialsCheckBox.Value=0; % Multiple trials begins unchecked
            evalin('base','projectStruct.Info.Flags.PlotMult=0;');
            app.SaveFigCheckBox.Value=0; % Save fig begins unchecked
            evalin('base','projectStruct.Info.Flags.SaveFIG=0;');
            app.SavePNGCheckBox.Value=0; % Save png begins unchecked
            evalin('base','projectStruct.Info.Flags.SavePNG=0;');
            app.SavetransparentPNGCheckBox.Value=0; % Save transparent png begins unchecked
            evalin('base','projectStruct.Info.Flags.SaveTransPNG=0;');
            app.SaveSVGCheckBox.Value=0; % Save svg begins unchecked
            evalin('base','projectStruct.Info.Flags.SaveSVG=0;');
            app.FcnSet=0; % Initialize group number to be 0.
            app.CurrGroupFcnNames{1}=''; % Initialize current group function names to be empty char.
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;
            if isempty(value)
                return; % Do nothing if the value is empty
            end
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectName=tempName;');
            app.projectName=value;
            % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
            userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
            documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
            fileName='allProjects_ProjectNamesPaths.txt';
            cd(documentsFolderPath);
            alreadyDone=0;
            if exist(fileName,'file')==2 % Previously existing file
                A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                for i=1:length(A)
                    if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                        alreadyDone=1;
                        app.LogsheetPathEditField.Value=A{i+1}(16:end);
                        assignin('base','logsheetPath',app.LogsheetPathEditField.Value);
                        evalin('base','projectStruct.Info.LogsheetPath=logsheetPath;');
                        app.ProjectPathEditField.Value=A{i+2}(15:end);
                        assignin('base','projectPath',app.ProjectPathEditField.Value);
                        evalin('base','projectStruct.Info.ProjectPath=projectPath;');
                        app.RootSaveFolderEditField.Value=A{i+3}(22:end);
                        assignin('base','rootSavePlotPath',app.RootSaveFolderEditField.Value);
                        evalin('base','projectStruct.Info.RootSavePlotPath=rootSavePlotPath;');
                        break;
                    end
                end
                if alreadyDone==0
                    for i=length(A):-1:1
                        appendLine=length(A);
                        if isequal(A{i},'') && ~isequal(A{i-1},'')
                            appendLine=i; % Remove all of the trailing newline chars
                            break;
                        end
                    end
                    A{appendLine+1}=['Project Name: ' app.projectName]; % Store the project name
                end
            else
                A{1}=['Project Name: ' app.projectName]; % New file
            end
            if alreadyDone==0
                fid=fopen([documentsFolderPath '\' fileName],'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
            end
            if alreadyDone==1
                cd(app.ProjectPathEditField.Value);
            end
            addpath('SpecifyPaths'); % Add the SpecifyPaths subfolder to the path.
            addpath('SpecifyTrials'); % Add the SpecifyTrials subfolder to the path.
            addpath('Import');
            addpath('Process');
            addpath('Plot');
            % Run the SpecifyPaths function if it exists.
            if exist(char(["specifyPaths_" app.projectName ".m"]),'file')
                feval(char(["specifyPaths_" app.projectName ".m"]));
            end
        end

        % Value changed function: LogsheetPathEditField
        function LogsheetPathEditFieldValueChanged(app, event)
            value = app.LogsheetPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.LogsheetPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                currCD=cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+1}=['Logsheet Path: ' value];
                            break;
                        end
                    end
                else
                    A{2}=['Logsheet Path: ' value];
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
                cd(currCD);
            end
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            value = app.ProjectPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+2}=['Project Path: ' value];
                            break;
                        end
                    end
                else
                    A{3}=['Project Path: ' value]; % New file
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
            end
            cd(value); % Set current directory to project path.
            addpath('SpecifyPaths'); % Add the SpecifyPaths subfolder to the path.
            addpath('SpecifyTrials'); % Add the SpecifyTrials subfolder to the path.
            addpath('Import');
            addpath('Process');
            addpath('Plot');
        end

        % Value changed function: RedoCheckBox
        function RedoCheckBoxValueChanged(app, event)
            value = app.RedoCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Redo=tempCheckbox;');
        end

        % Value changed function: AddDataTypesCheckBox
        function AddDataTypesCheckBoxValueChanged(app, event)
            value = app.AddDataTypesCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.AddDataTypes=tempCheckbox;');
        end

        % Value changed function: UpdateMetadataOnlyCheckBox
        function UpdateMetadataOnlyCheckBoxValueChanged(app, event)
            value = app.UpdateMetadataOnlyCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=tempCheckbox;');
        end

        % Value changed function: ReloadExistingDataCheckBox
        function ReloadExistingDataCheckBoxValueChanged(app, event)
            value = app.ReloadExistingDataCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=tempCheckbox;');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value);
            % Re-initialize project info if they don't exist
            assignin('base','projectName',app.ProjectNameEditField.Value);
            assignin('base','logsheetPath',app.LogsheetPathEditField.Value);
            assignin('base','projectPath',app.ProjectPathEditField.Value);
            evalin('base','projectStruct.Info.ProjectName=projectName;')
            evalin('base','projectStruct.Info.LogsheetPath=logsheetPath;')
            evalin('base','projectStruct.Info.ProjectPath=projectPath;')
            % Re-initialize flags if they don't exist.
            assignin('base','importchkbox',app.RedoCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Redo=importchkbox;');
            assignin('base','importchkbox',app.AddDataTypesCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.AddDataTypes=importchkbox;');
            assignin('base','importchkbox',app.UpdateMetadataOnlyCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=importchkbox;');
            assignin('base','importchkbox',app.ReloadExistingDataCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=importchkbox;');
            tic;
            evalin('base','projectStruct=importProject(projectStruct);'); % Run the import.
            toc;
        end

        % Value changed function: FunctionNamesTextArea
        function FunctionNamesTextAreaValueChanged(app, event)
            value = app.FunctionNamesTextArea.Value;
            assignin('base','fcnNames',value);
            app.CurrGroupFcnNames=value;
            disp('Functions in Current Group Changed To:');
            app.CurrGroupFcnNames
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Are You Sure You Want to Close?','Confirm Close',...
                'Icon','warning');
            if isequal(selection,'OK')
                delete(app);
            end
        end

        % Button pushed function: 
        % AddFunctionstoGroupSavetoFileButton
        function AddFunctionstoGroupSavetoFileButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value); % CD to the project path.
            if app.FcnSet==0 || isequal(app.CurrGroupFcnNames{1},'')
                disp('NEED TO SET UP A GROUP FIRST!');
                beep;
                return;
            end
            % Store the current set of functions into the master list. Will overwrite the group.
            app.allFcnNames{app.FcnSet}=app.CurrGroupFcnNames;
            disp(['Functions Added to Group ' num2str(app.FcnSet) ':']);
            app.CurrGroupFcnNames % Display the function names in this group added to the master list
            % Save the group number & function names to file.
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2
                f=fopen(currName,'w'); % Create or open file.
                fclose(f); % Close file, because fileread doesn't need it.
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file line by line
            fileLength=length(A); % Number of lines already in the file.
            sameGroupExists=0; % Initialize that the group does not exist in this file.
            higherGroupExists=0;
            lowerGroupExists=0;
            if fileLength==1 % Starting a new file.
                lineNum=0; % Ensure that writing to the file begins at the first line.
                B={};
                for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                    if i==1 % Add group number at beginning of group
                        lineNum=lineNum+1;
                        B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                    end
                    lineNum=lineNum+1;
                    B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                end
            else
                % Check if overwriting a group or adding a group. If adding, ensure it goes at proper position.
                % Overwriting.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && isequal(str2double(A{i}(end-1)),app.FcnSet)) || (app.FcnSet>=10 && app.FcnSet<100 && isequal(str2double(A{i}(end-2:end-1)),app.FcnSet)))
                        sameGroupExists=1; % Group number found in the file.
                        startInsertLine=i; % Line number of the group number
                    end
                    if sameGroupExists==1 % Check for first empty line after the group
                        if isequal(A{i},'')
                            lastLine=i; % Line number of the empty line after the group
                            break;
                        end
                    end
                end
                if sameGroupExists==1 % Replace a previously written group
                    % Copy over everything before the overwritten group
                    if startInsertLine>1
                        B=A(1:startInsertLine-1); % Ends with a '' just before the group name
                    else
                        B={};
                    end
                    % Overwrite the group
                    lineNum=startInsertLine-1; % The line with a '' just before the group name
                    for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                        if i==1 % Add group number at beginning of group
                            lineNum=lineNum+1;
                            B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                        end
                        lineNum=lineNum+1;
                        B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                    end
                    % Add in the rest of the group function names, if any.
                    if lastLine<length(A) % Check if there are any more groups
                        B=horzcat(B,A(lastLine:end));
                    end
                elseif sameGroupExists==0
                    % Adding new group.
                    % Case 1: Group number lower than all existing groups (goes at start of file)
                    % Case 2: Group number in middle of all existing groups (goes in middle of file)
                    % Case 3: Group number larger than all existing groups (goes at end of file)
                    % Check for lower groups
                    for i=1:length(A)
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && str2double(A{i}(end-1))<app.FcnSet) || (app.FcnSet>=10 && app.FcnSet<100 && str2double(A{i}(end-2:end-1))<app.FcnSet))
                            lowerGroupExists=1; % Group number found in the file.
                        end
                        % Check for higher groups
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && str2double(A{i}(end-1))>app.FcnSet) || (app.FcnSet>=10 && app.FcnSet<100 && str2double(A{i}(end-2:end-1))>app.FcnSet))
                            higherGroupExists=1; % Group number found in the file.
                            firstLine=i; % Line number of first function of the higher group
                        end
                    end
                    % Case 1 and 3
                    if (higherGroupExists==1 && lowerGroupExists~=1) || (higherGroupExists~=1 && lowerGroupExists==1)
                        % Write the group to file
                        lineNum=0; % The line with a '' just before the group name
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                        end
                        if higherGroupExists==1 && lowerGroupExists~=1 % Case 1
                            B{lineNum+1}='';
                            B=horzcat(B,A); % Append A to B because all groups are higher than the current.
                        elseif higherGroupExists~=1 && lowerGroupExists==1 % Case 3
                            appendLine=length(A); % By default, the last line.
                            for i=length(A):-1:1
                                if isequal(A{i},'') && ~isequal(A{i-1},'') % First newline after the last function name.
                                    appendLine=i;
                                    break;
                                end
                            end
                            B=horzcat(A(1:appendLine),B); % Append B to A because all groups are lower than the the current. Avoids including many newline characters at the end.
                        end
                    end
                    % Case 2
                    if higherGroupExists==1 && lowerGroupExists==1
                        B=A(1:firstLine-1); % Set B to be everything in A up to and including the empty line before the new group.
                        lineNum=firstLine-1; % The '' line before the higher group.
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                        end
                        B=horzcat(B,A(firstLine-1:end)); % Append the rest of A to the file with the new group.
                    end
                end
            end
            fid=fopen(currName,'w');
            fprintf(fid,'%s\n',B{:});
            fclose(fid);
        end

        % Value changed function: FunctionGroupEditField
        function FunctionGroupEditFieldValueChanged(app, event)
            value = app.FunctionGroupEditField.Value;
            assignin('base','fcnSet',value); % Assign the function group number specified to base.
            app.FcnSet=value; % Store the function group number.
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab; % Called when switching from Setup to Run
            if ~isequal(selectedTab.Title,'Run') % In the Run tab
                return;
            end
            app.numRunTabs=app.numRunTabs+1;
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                groupList={'NULL'};
                app.allFcnNames=groupList;
            else
                % Iterate through the number of processing function groups to set up the drop down list.
                newMax=0;
                currName=['functionNames_' app.projectName '.txt'];
                funcCount=0;
                if exist(currName,'file')~=2 % Check that a functionNames file exists.
                    disp('No function names file for this project!');
                end
                A=regexp(fileread(currName),'\n','split'); % Read the file.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                        if regexp(A{i}(end-2),'[0-9]')
                            fcnSet=A{i}(end-2:end-1);
                        else
                            fcnSet=A{i}(end-1);
                        end
                        fcnSet=str2double(fcnSet);
                    elseif ~isequal(A{i},'') % This line is a function name.
                        funcCount=funcCount+1;
                        app.procFunc(funcCount).Name=A{i}; % Add function name
                        app.procFunc(funcCount).SpecifyTrialsCount=fcnSet; % Add function group number
                        if fcnSet>newMax
                            newMax=fcnSet;
                        end
                    end
                end
                groupList=cell(newMax,1); % Initialize list of groups.
                for i=1:length(groupList)
                    groupList{i,1}=['Group ' num2str(i)];
                end
                app.DropDown.Items=groupList; % Set dropdown list according to the number of groups.
                currDropdownVal = app.DropDown.Value;
                if ~isequal(currDropdownVal,'NULL')
                    app.groupNum=str2double(currDropdownVal(end)); % Group number
                else
                    app.groupNum=1;
                end
                procFuncCount=0; % Initialize all of the Redo flags to be 0.
                currName=['functionNames_' app.projectName '.txt'];
                currGroupNames=''; % Initialize.
                if exist(currName,'file')==2
                    A=regexp(fileread(currName),'\n','split'); % Read thes file.
                    for i=1:length(A)
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                            funcCount=0;
                            if regexp(A{i}(end-2),'[0-9]')
                                fcnSet=A{i}(end-2:end-1);
                            else
                                fcnSet=A{i}(end-1);
                            end
                            fcnSet=str2double(fcnSet);
                            for j=1:10
                                app.redoVal{fcnSet}{j}=0; % Set the value of the checkboxes to start at 0 for each group.
                            end
                        elseif ~isequal(A{i},'') % This line is a function name.
                            procFuncCount=procFuncCount+1;
                            funcCount=funcCount+1;
                            fcnName=regexp(A{i},'(','split');
                            if length(char(fcnName{2}))>1
                                argNames=char(fcnName{2}(1:end-1)); % Omit the ending parantheses
                            else
                                argNames='';
                            end
                            fcnName=char(fcnName{1});
                            app.procFunc(procFuncCount).Redo=0;
                            app.procFunc(procFuncCount).Name=fcnName; % Add function name
                            app.procFunc(procFuncCount).SpecifyTrialsCount=fcnSet; % Add function group number
                            app.allFcnNames{fcnSet}{funcCount}=fcnName; % List of current function names.
                            app.groupArgs{fcnSet}{funcCount}=argNames; % List of arguments from file.
                        end
                    end
                else
                    currGroupNames='NULL';
                end
                for i=1:10 % Set the checkboxes visibility to match the current group.
                    app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                    if i<=length(app.allFcnNames{app.groupNum}) && ~isequal(currGroupNames,'NULL') % Check if this group exists.
                        %                         app.(['Fcn' num2str(i) 'Label']).Visible='On';
                        %                         app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                        app.(['CheckBox_' num2str(i)]).Visible='On';
                    else
                        app.(['CheckBox_' num2str(i)]).Visible='Off';
                        %                         app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                    end
                end
                for i=1:length(app.allFcnNames{app.groupNum})
                    currNames{i,1}=regexp(app.allFcnNames{app.groupNum}{i},'(','split');
                    currNames{i,1}=char(currNames{i,1}{1});
                end
                app.allFcnNames{app.groupNum}=currNames;
                app.FunctionNamesTextArea_2.Value=app.allFcnNames{app.groupNum};
                app.FunctionNamesTextArea_2.Editable=0;
                try
                    app.FunctionArgsTextArea.Value=app.groupArgs{app.groupNum};
                    app.FunctionArgsTextArea.Editable=1;
                catch
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.groupNum=str2double(value(end)); % Group number
            currGroupNames=app.allFcnNames{app.groupNum}; % List of current function names.
            for i=1:10 % Iterate over all labels.
                app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                if i<=length(currGroupNames) % Check if this group exists.
                    %                     app.(['Fcn' num2str(i) 'Label']).Visible='On';
                    %                     app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                    app.(['CheckBox_' num2str(i)]).Visible='On';
                    if app.groupNum<=length(app.redoVal) % When operating only from file, didn't enter/load all functions into app
                        app.(['CheckBox_' num2str(i)]).Value=app.redoVal{app.groupNum}{i}; % Set the checkbox to the stored value.
                    end
                else
                    app.(['CheckBox_' num2str(i)]).Visible='Off';
                    %                     app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                end
            end
            app.FunctionNamesTextArea_2.Value=app.allFcnNames{app.groupNum};
            app.FunctionNamesTextArea_2.Editable=0;
            app.FunctionArgsTextArea.Editable=1;
            try
                app.FunctionArgsTextArea.Value=app.groupArgs{app.groupNum};
            catch
                app.FunctionArgsTextArea.Value={''};
            end
        end

        % Button pushed function: OpenimportSettingsButton
        function OpenimportSettingsButtonPushed(app, event)
            if evalin('base',"exist(['importSettings' projectStruct.Info.ProjectName]);")==2 % Check if file already exists.
                evalin('base',"edit(['importSettings' projectStruct.Info.ProjectName]);"); % Open file. Never overwrites an existing file.
            else
                % Make & format the file if it does not already exist (based on template).
                evalin('base',"currName=['importSettings' projectStruct.Info.ProjectName '.m'];");
                evalin('base',"copyfile('importSettingsTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [ProjHelper,dataTypes,segment]=' currName(1:end-2) '(subjectListInStruct,markerName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
        end

        % Button pushed function: 
        % LoadFunctionNamesinGroupFromFileButton
        function LoadFunctionNamesinGroupFromFileButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value);
            currName=['functionNames_' app.projectName '.txt'];
            if isempty(app.projectName)
                disp('Forgot to specify project name!');
                return;
            end
            if exist(currName,'file')==2 % Check that a functionNames file exists.
                A=regexp(fileread(currName),'\n','split'); % Read the file.
                sameGroupExists=0; % Initialize that the group does not exist in this file.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && isequal(str2double(A{i}(end-1)),app.FcnSet)) || (app.FcnSet>=10 && app.FcnSet<100 && isequal(str2double(A{i}(end-2:end-1)),app.FcnSet)))
                        sameGroupExists=1; % Group number found in the file.
                        firstFcnLine=i+1;
                    end
                    if sameGroupExists==1 % Check for first empty line after the group
                        if isequal(A{i},'')
                            lastFcnLine=i-1;
                            break;
                        elseif i==length(A)
                            lastFcnLine=i;
                        end
                    end
                end
                if sameGroupExists==0
                    disp('Wrong group number!');
                    return;
                end
                % Put the function names into the text area
                currFcnNames=cell(lastFcnLine-firstFcnLine+1,1);
                fcnCount=0;
                for i=firstFcnLine:lastFcnLine
                    fcnCount=fcnCount+1;
                    currFcnName=regexp(A{i},'(','split');
                    currFcnName=char(currFcnName{1});
                    currFcnNames{fcnCount}=currFcnName;
                end
                app.FunctionNamesTextArea.Value=currFcnNames;
                app.CurrGroupFcnNames=currFcnNames; % Store current function names to app property.
                app.allFcnNames{app.FcnSet}=app.CurrGroupFcnNames; % Store the current function names to the master list.
            else
                disp('No function names file for this project!');
            end
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{1})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{1}=value;
                end
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{2})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{2}=value;
                end
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{3})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{3}=value;
                end
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{4})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{4}=value;
                end
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{5})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{5}=value;
                end
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{6})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{6}=value;
                end
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{7})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{7}=value;
                end
            end
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{8})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{8}=value;
                end
            end
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{9})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{9}=value;
                end
            end
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            for i=1:length(app.procFunc)
                if isequal(app.procFunc(i).Name,app.allFcnNames{app.groupNum}{10})
                    app.procFunc(i).Redo=value;
                    app.redoVal{app.groupNum}{10}=value;
                end
            end
        end

        % Value changed function: FunctionArgsTextArea
        function FunctionArgsTextAreaValueChanged(app, event)
            value = app.FunctionArgsTextArea.Value;
            % When switching to the tab, should display the current group's args from file (if any)
            % When the value is changed, it should associate the function args with the processing function names in the app.procFunc variable.
            groupCount=0;
            for i=1:length(app.procFunc) % Iterate through all procFunc names
                if ~isempty(app.procFunc(i).Name) % Shouldn't happen but occasionally apparently does.
                    if any(contains(app.allFcnNames{app.groupNum},app.procFunc(i).Name)) % If the current name in the group matches an existing name.
                        groupCount=groupCount+1; % Increment the count within the group.
                        if groupCount<=length(value) % Args specified for this function, even if empty
                            app.procFunc(i).Args={value{groupCount}}; % Add all args of this group to the procFunc variable.
                            tempGroupArgs{groupCount}=value{groupCount}; % Add all args of this group to the group variable.
                        else
                            app.procFunc(i).Args={''}; % Add all args of this group to the procFunc variable.
                            tempGroupArgs{groupCount}=''; % Add all args of this group to the group variable.
                        end
                    end
                end
            end
            app.groupArgs{app.groupNum}=tempGroupArgs; % Prevents elements later down the list from reappearing after deletion.
        end

        % Button pushed function: SaveArgstoFileButton
        function SaveArgstoFileButtonPushed(app, event)
            % Save the current group's args to the functionNames file.
            cd(app.ProjectPathEditField.Value);
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('Missing function names file!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            funcCount=0;
            correctGroup=0;
            for i=1:length(A)
                if length(A{i})>5 && isequal(A{i}(1:5),'Group') && isequal(str2double(A{i}(7)),app.groupNum)
                    correctGroup=1; % Indicates that this is the correct group
                elseif ~isequal(A{i},'') && correctGroup==1 % This line is a function name.
                    funcCount=funcCount+1;
                    fcnName=regexp(A{i},'(','split');
                    fcnName=char(fcnName{1});
                    A{i}=strcat([fcnName '(' app.groupArgs{app.groupNum}{funcCount} ')']); % Add args to the file.
                    app.procFunc(funcCount).Args=app.groupArgs{app.groupNum}{funcCount};
                elseif isequal(A{i},'') && correctGroup==1
                    break; % Stop processing after one group.
                end
            end
            fid=fopen(currName,'w');
            fprintf(fid,'%s\n',A{:});
            fclose(fid);
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('No function names file for this project!');
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            funcCount=0;
            for i=1:length(A)
                if length(A{i})>5 && isequal(A{i}(1:5),'Group')
                    fcnSet=str2double(A{i}(7));
                elseif ~isequal(A{i},'')
                    funcCount=funcCount+1;
                    openParenIdx=find(A{i}=='(',1,'first');
                    fcnName=A{i}(1:openParenIdx-1);
                    if openParenIdx+1<length(A{i})
                        argNames=A{i}(openParenIdx+1:end-1); % Omit the ending parentheses
                    else
                        argNames='';
                    end
                    % Ensure that procFunc reads all of the most up to date changes from the text file.
                    app.procFunc(i).Name=fcnName;
                    app.procFunc(i).Args=argNames;
                    app.procFunc(i).SpecifyTrialsCount=fcnSet;
                    % Redo ishould be automatically set to 0 just by being on this tab.
                    if ~isfield(app.procFunc(i),'Redo') || isempty(app.procFunc(i).Redo)
                        app.procFunc(i).Redo=0;
                    end
                    currProcFunc(funcCount)=app.procFunc(i);
                end
            end
            cd(app.ProjectPathEditField.Value);
            dbstop if caught error;
            assignin('base','procFunc',currProcFunc); % Assign the processing function names & metadata to the base workspace.
            evalin('base','[projectStruct]=processData_GUI(projectStruct,procFunc);'); % Run the data processing.
            dbclear if caught error;
        end

        % Button pushed function: OpenspecifyTrialsButton
        function OpenspecifyTrialsButtonPushed(app, event)
            if app.FcnSet==0
                return;
            end
            evalin('base','cd(projectStruct.Info.ProjectPath)'); % Set the current directory to the project folder.
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            evalin('base',"currName=['specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '.m'];");
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    assignin('base','tempName',listing(i).name); % Assign the current file name to base.
                    if evalin('base','isequal(tempName,currName)') % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                evalin('base',char("copyfile('specifyTrials_Template.m',currName);")); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [inclStruct]=specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '(logsheetPath,projectPath,projectName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
            cd(currCD);
        end

        % Button pushed function: 
        % OpenspecifyTrials_importProjectButton
        function OpenspecifyTrials_importProjectButtonPushed(app, event)
            % Open the specifyTrials function for this project's importProject
            evalin('base','cd(projectStruct.Info.ProjectPath)'); % Set the current directory to the project folder.
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            evalin('base',"currName=['specifyTrials_' projectStruct.Info.ProjectName '_importProject.m'];");
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    assignin('base','tempName',listing(i).name); % Assign the current file name to base.
                    if evalin('base','isequal(tempName,currName)') % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                evalin('base',char("copyfile('specifyTrials_Template.m',currName);")); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [inclStruct]=specifyTrials_' projectStruct.Info.ProjectName '_importProject(logsheetPath,projectPath,projectName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
            cd(currCD);
        end

        % Button pushed function: 
        % NewProcessingFunctionTrialLevelButton
        function NewProcessingFunctionTrialLevelButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Process_trialTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionSubjectLevelButton
        function NewProcessingFunctionSubjectLevelButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];");
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Process_subjectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,dataStruct,currSubTrialsList,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionProjectLevelButton
        function NewProcessingFunctionProjectLevelButtonPushed(app, event)
            cd(app.ProjectPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Process_projectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [projectStruct]=' currName(1:end-2) '(projectStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % If there's a function names file already, load the function names into app.allFcnNames when going to Process tab
            if ~isequal(selectedTab.Title,'Process')
                return;
            end
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            for i=1:length(A)
                if length(A{i})>5 && isequal(A{i}(1:5),'Group')
                    fcnSet=str2double(A{i}(7));
                    funcCount=0;
                elseif ~isequal(A{i},'')
                    funcCount=funcCount+1;
                    fcnName=regexp(A{i},'(','split');
                    fcnName=char(fcnName{1});
                    app.allFcnNames{fcnSet}{funcCount}=fcnName;
                end
            end
        end

        % Callback function
        function XLimitsEditFieldValueChanged(app, event)
            value = app.XLimitsEditField.Value;
            app.XLims=value;
        end

        % Callback function
        function YLimitsEditFieldValueChanged(app, event)
            value = app.YLimitsEditField.Value;
            app.YLims=value;
        end

        % Callback function
        function ZLimitsEditFieldValueChanged(app, event)
            value = app.ZLimitsEditField.Value;
            app.ZLims=value;
        end

        % Value changed function: RootSaveFolderEditField
        function RootSaveFolderEditFieldValueChanged(app, event)
            value = app.RootSaveFolderEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.SavePlotPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                currCD=cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+3}=['Save Plot Root Path: ' value];
                            break;
                        end
                    end
                else
                    A{4}=['Save Plot Root Path: ' value];
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
                cd(currCD);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Run one plotting function
            cd(app.ProjectPathEditField.Value);
            assignin('plotFunc',app.plotFuncName);
            evalin('base','plotData_GUI(projectStruct,plotFunc);');
            dbstop if caught error;
        end

        % Button pushed function: RunGroupButton
        function RunGroupButtonPushed(app, event)
            % Run the group with the function names and args saved in the file, NOT in the GUI (if different)
            cd(app.ProjectPathEditField.Value);            
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('Missing function names file!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            correctGroup=0;
            funcCount=0;
            for i=1:length(A)
                if length(A{i})>5 && isequal(A{i}(1:5),'Group') && isequal(str2double(A{i}(7)),app.groupNum)
                    correctGroup=1;
                    fcnSet=str2double(A{i}(7));
                elseif ~isequal(A{i},'') && correctGroup==1
                    funcCount=funcCount+1;
                    openParenIdx=find(A{i}=='(',1,'first');
                    fcnName=A{i}(1:openParenIdx-1);
                    if openParenIdx+1<length(A{i})
                        argNames=A{i}(openParenIdx+1:end-1); % Omit the ending parentheses
                    else
                        argNames='';
                    end
                    app.procFunc(i).Name=fcnName;
                    app.procFunc(i).Args=argNames;
                    app.procFunc(i).SpecifyTrialsCount=fcnSet;
                    if ~isfield(app.procFunc(i),'Redo') || isempty(app.procFunc(i).Redo)
                        app.procFunc(i).Redo=0;
                    end
                    currProcFunc(funcCount)=app.procFunc(i);
                elseif isequal(A{i},'') && correctGroup==1
                    break;
                end
            end
            assignin('base','procFunc',currProcFunc); % Assign the processing function names & metadata to the base workspace.
            evalin('base','projectStruct.Info.Flags.Org=0;'); % Ensure that trial names are listed by subject, not by conditions.
%             try
                tic;
                dbstop if caught error;
                evalin('base','[projectStruct]=processData_GUI(projectStruct,procFunc);'); % Run the data processing.
                toc;
                dbclear if caught error;
%             catch e
%                 %                 fullFile=[fileparts(which(e.stack(1).name)) '\' e.stack(1).name];
%                 cd(app.ProjectPathEditField.Value);
%                 s=dbstatus;                
%                 file=e.stack(1).name;
%                 line=e.stack(1).line;
%                 hotlinkName=['<a href="matlab:opentoline(''' e.stack(1).name ''',' num2str(e.stack(1).line) ')">line ' num2str(e.stack(1).line) '</a>'];
%                 %                 hotlinkcode=sprintf('Go to <a href="matlab: matlab.desktop.editor.openAndGoToLine(which(''%s''), %d);">%s line %d</a>', file, line, file, line);
%                 disp(['Error in ' e.stack(1).name ' ' hotlinkName ': ' e.identifier]);
%                 for i=1:length(s)
%                     if isequal(file,s(i).name)
%                         break;
%                     end
%                 end
%                 if ~isequal(e.stack(1).line,s(i).line) % Don't overwrite the breakpoints
%                     eval(['dbstop in ' file ' at ' num2str(line)]); % Stop because of the error (enables dbquit)
%                 end
%                 %                 closeNoPrompt(matlab.desktop.editor.getActive); % Close the app management service window
%                 
%             end
        end

        % Value changed function: PlottingFunctionNameEditField
        function PlottingFunctionNameEditFieldValueChanged(app, event)
            value = app.PlottingFunctionNameEditField.Value;
            app.plotFuncName=value;
        end

        % Value changed function: MultipleTrialsCheckBox
        function MultipleTrialsCheckBoxValueChanged(app, event)
            value = app.MultipleTrialsCheckBox.Value;
            app.PlotMult=value;
        end

        % Value changed function: SaveFigCheckBox
        function SaveFigCheckBoxValueChanged(app, event)
            value = app.SaveFigCheckBox.Value;
            app.SaveFIG=value;
        end

        % Value changed function: SavePNGCheckBox
        function SavePNGCheckBoxValueChanged(app, event)
            value = app.SavePNGCheckBox.Value;
            app.SavePNG=value;
        end

        % Value changed function: SavetransparentPNGCheckBox
        function SavetransparentPNGCheckBoxValueChanged(app, event)
            value = app.SavetransparentPNGCheckBox.Value;
            app.SaveTransPNG=value;
        end

        % Value changed function: SaveSVGCheckBox
        function SaveSVGCheckBoxValueChanged(app, event)
            value = app.SaveSVGCheckBox.Value;
            app.SaveSVG=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.LogsheetPathEditFieldLabel = uilabel(app.ImportTab);
            app.LogsheetPathEditFieldLabel.Text = 'Logsheet Path';
            app.LogsheetPathEditField = uieditfield(app.ImportTab, 'text');
            app.LogsheetPathEditField.ValueChangedFcn = createCallbackFcn(app, @LogsheetPathEditFieldValueChanged, true);
            app.LogsheetPathEditField.Value = 'Full Path to Logsheet Excel File';
            app.ProjectNameEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectNameEditField.Value = 'Project Name';
            app.ProjectPathEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.ProjectPathEditField.Value = 'Full Path to Project Processing Folder';
            app.RunButton = uibutton(app.ImportTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RedoCheckBox = uicheckbox(app.ImportTab);
            app.RedoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedoCheckBoxValueChanged, true);
            app.RedoCheckBox.Text = 'Redo';
            app.AddDataTypesCheckBox = uicheckbox(app.ImportTab);
            app.AddDataTypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddDataTypesCheckBoxValueChanged, true);
            app.AddDataTypesCheckBox.Text = 'Add Data Types';
            app.UpdateMetadataOnlyCheckBox = uicheckbox(app.ImportTab);
            app.UpdateMetadataOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateMetadataOnlyCheckBoxValueChanged, true);
            app.UpdateMetadataOnlyCheckBox.Text = 'Update Metadata Only';
            app.ReloadExistingDataCheckBox = uicheckbox(app.ImportTab);
            app.ReloadExistingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReloadExistingDataCheckBoxValueChanged, true);
            app.ReloadExistingDataCheckBox.Text = 'Reload Existing Data';
            app.OpenimportSettingsButton = uibutton(app.ImportTab, 'push');
            app.OpenimportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimportSettingsButtonPushed, true);
            app.OpenimportSettingsButton.Text = 'Open importSettings';
            app.OpenspecifyTrials_importProjectButton = uibutton(app.ImportTab, 'push');
            app.OpenspecifyTrials_importProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrials_importProjectButtonPushed, true);
            app.OpenspecifyTrials_importProjectButton.Text = 'Open specifyTrials_importProject';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.TabGroup2 = uitabgroup(app.ProcessTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = 'Group functions together based on what order they''re run and which set of trials they''re run on';
            app.FunctionNamesLabel = uilabel(app.SetupTab);
            app.FunctionNamesLabel.Text = 'Function Names';
            app.FunctionNamesTextArea = uitextarea(app.SetupTab);
            app.FunctionNamesTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionNamesTextAreaValueChanged, true);
            app.FunctionGroupEditFieldLabel = uilabel(app.SetupTab);
            app.FunctionGroupEditFieldLabel.Text = 'Function Group #';
            app.FunctionGroupEditField = uieditfield(app.SetupTab, 'numeric');
            app.FunctionGroupEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionGroupEditFieldValueChanged, true);
            app.OpenspecifyTrialsButton = uibutton(app.SetupTab, 'push');
            app.OpenspecifyTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrialsButtonPushed, true);
            app.OpenspecifyTrialsButton.Text = 'Open specifyTrials';
            app.AddFunctionstoGroupSavetoFileButton = uibutton(app.SetupTab, 'push');
            app.AddFunctionstoGroupSavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunctionstoGroupSavetoFileButtonPushed, true);
            app.AddFunctionstoGroupSavetoFileButton.Text = 'Add Functions to Group & Save to File';
            app.ClickoutsideboxwhendoneLabel = uilabel(app.SetupTab);
            app.ClickoutsideboxwhendoneLabel.Text = 'Click outside box when done.';
            app.LoadFunctionNamesinGroupFromFileButton = uibutton(app.SetupTab, 'push');
            app.LoadFunctionNamesinGroupFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFunctionNamesinGroupFromFileButtonPushed, true);
            app.LoadFunctionNamesinGroupFromFileButton.Text = 'Load Function Names in Group From File';
            app.NewProcessingFunctionTrialLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionTrialLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionTrialLevelButtonPushed, true);
            app.NewProcessingFunctionTrialLevelButton.Text = 'New Processing Function: Trial Level';
            app.NewProcessingFunctionSubjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionSubjectLevelButtonPushed, true);
            app.NewProcessingFunctionSubjectLevelButton.Text = 'New Processing Function: Subject Level';
            app.NewProcessingFunctionProjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionProjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionProjectLevelButtonPushed, true);
            app.NewProcessingFunctionProjectLevelButton.Text = 'New Processing Function: Project Level';
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.DropDownLabel = uilabel(app.RunTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RunTab);
            app.DropDown.Items = {'Group 1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Group 1';
            app.RedoFcnLabel = uilabel(app.RunTab);
            app.RedoFcnLabel.Text = 'Redo Fcn';
            app.CheckBox_1 = uicheckbox(app.RunTab);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.RunGroupButton = uibutton(app.RunTab, 'push');
            app.RunGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RunGroupButtonPushed, true);
            app.RunGroupButton.Text = 'Run Group';
            app.SaveArgstoFileButton = uibutton(app.RunTab, 'push');
            app.SaveArgstoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArgstoFileButtonPushed, true);
            app.SaveArgstoFileButton.Text = 'Save Args to File';
            app.RunAllButton = uibutton(app.RunTab, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.CheckBox_2 = uicheckbox(app.RunTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.RunTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.RunTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.RunTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.RunTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.RunTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.RunTab);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.RunTab);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.RunTab);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '10';
            app.FunctionNamesTextArea_2Label = uilabel(app.RunTab);
            app.FunctionNamesTextArea_2Label.Text = 'FunctionNames';
            app.FunctionNamesTextArea_2 = uitextarea(app.RunTab);
            app.FunctionArgsTextAreaLabel = uilabel(app.RunTab);
            app.FunctionArgsTextAreaLabel.Text = 'FunctionArgs';
            app.FunctionArgsTextArea = uitextarea(app.RunTab);
            app.FunctionArgsTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionArgsTextAreaValueChanged, true);
            app.DropDown2 = uidropdown(app.RunTab);
            app.OpenFunctionButton = uibutton(app.RunTab, 'push');
            app.OpenFunctionButton.Text = 'Open Function';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.MultipleTrialsCheckBox = uicheckbox(app.PlotTab);
            app.MultipleTrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipleTrialsCheckBoxValueChanged, true);
            app.MultipleTrialsCheckBox.Text = 'Multiple Trials';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveFigCheckBox = uicheckbox(app.PlotTab);
            app.SaveFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFigCheckBoxValueChanged, true);
            app.SaveFigCheckBox.Text = 'Save Fig';
            app.SavePNGCheckBox = uicheckbox(app.PlotTab);
            app.SavePNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePNGCheckBoxValueChanged, true);
            app.SavePNGCheckBox.Text = 'Save PNG';
            app.SavetransparentPNGCheckBox = uicheckbox(app.PlotTab);
            app.SavetransparentPNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavetransparentPNGCheckBoxValueChanged, true);
            app.SavetransparentPNGCheckBox.Text = 'Save transparent PNG';
            app.NewPlottingFunctionTrialLevelButton = uibutton(app.PlotTab, 'push');
            app.NewPlottingFunctionTrialLevelButton.Text = 'New Plotting Function - Trial Level';
            app.RootSaveFolderEditFieldLabel = uilabel(app.PlotTab);
            app.RootSaveFolderEditFieldLabel.Text = 'Root Save Folder';
            app.RootSaveFolderEditField = uieditfield(app.PlotTab, 'text');
            app.RootSaveFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RootSaveFolderEditFieldValueChanged, true);
            app.RootSaveFolderEditField.Value = 'Full Folder Path';
            app.CurrentSaveSubfolderEditFieldLabel = uilabel(app.PlotTab);
            app.CurrentSaveSubfolderEditFieldLabel.Text = 'Current Save Subfolder';
            app.CurrentSaveSubfolderEditField = uieditfield(app.PlotTab, 'text');
            app.CurrentSaveSubfolderEditField.Value = 'Folder Name';
            app.SaveSVGCheckBox = uicheckbox(app.PlotTab);
            app.SaveSVGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveSVGCheckBoxValueChanged, true);
            app.SaveSVGCheckBox.Text = 'Save SVG';
            app.PlottingFunctionNameEditFieldLabel = uilabel(app.PlotTab);
            app.PlottingFunctionNameEditFieldLabel.Text = 'Plotting Function Name';
            app.PlottingFunctionNameEditField = uieditfield(app.PlotTab, 'text');
            app.PlottingFunctionNameEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingFunctionNameEditFieldValueChanged, true);
            app.NewPlottingFunctionSubjectLevelButton = uibutton(app.PlotTab, 'push');
            app.NewPlottingFunctionSubjectLevelButton.Text = 'New Plotting Function - Subject Level';
            app.NewPlottingFunctionProjectLevelButton = uibutton(app.PlotTab, 'push');
            app.NewPlottingFunctionProjectLevelButton.Text = 'New Plotting Function - Project Level';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end