classdef ProcessingAppV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        LogsheetPathEditFieldLabel      matlab.ui.control.Label
        LogsheetPathEditField           matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectDataPathEditFieldLabel   matlab.ui.control.Label
        ProjectDataPathEditField        matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        RedoCheckBox                    matlab.ui.control.CheckBox
        AddDataTypesCheckBox            matlab.ui.control.CheckBox
        UpdateMetadataOnlyCheckBox      matlab.ui.control.CheckBox
        ReloadExistingDataCheckBox      matlab.ui.control.CheckBox
        OpenimportSettingsButton        matlab.ui.control.Button
        OpenspecifyTrials_importProjectButton  matlab.ui.control.Button
        ProjectProcessingPathLabel      matlab.ui.control.Label
        ProjectProcessingPathEditField  matlab.ui.control.EditField
        DropDown2_4                     matlab.ui.control.DropDown
        ProcessTab                      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        FunctionNamesLabel              matlab.ui.control.Label
        FunctionNamesTextArea           matlab.ui.control.TextArea
        FunctionGroupEditFieldLabel     matlab.ui.control.Label
        FunctionGroupEditField          matlab.ui.control.NumericEditField
        OpenspecifyTrialsButton         matlab.ui.control.Button
        AddFunctionstoGroupSavetoFileButton  matlab.ui.control.Button
        ClickoutsideboxwhendoneLabel    matlab.ui.control.Label
        LoadFunctionNamesinGroupFromFileButton  matlab.ui.control.Button
        NewProcessingFunctionTrialLevelButton  matlab.ui.control.Button
        NewProcessingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewProcessingFunctionProjectLevelButton  matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        RedoFcnLabel                    matlab.ui.control.Label
        CheckBox_1                      matlab.ui.control.CheckBox
        RunGroupButton                  matlab.ui.control.Button
        SaveArgstoFileButton            matlab.ui.control.Button
        RunAllButton                    matlab.ui.control.Button
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        FunctionNamesTextArea_2Label    matlab.ui.control.Label
        FunctionNamesTextArea_2         matlab.ui.control.TextArea
        FunctionArgsTextAreaLabel       matlab.ui.control.Label
        FunctionArgsTextArea            matlab.ui.control.TextArea
        DropDown2                       matlab.ui.control.DropDown
        OpenFunctionButton              matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        MultipleTrialsCheckBox          matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        SaveFigCheckBox                 matlab.ui.control.CheckBox
        SavePNGCheckBox                 matlab.ui.control.CheckBox
        SavetransparentPNGCheckBox      matlab.ui.control.CheckBox
        RootSaveFolderEditFieldLabel    matlab.ui.control.Label
        RootSaveFolderEditField         matlab.ui.control.EditField
        CurrentSaveSubfolderEditFieldLabel  matlab.ui.control.Label
        CurrentSaveSubfolderEditField   matlab.ui.control.EditField
        SaveSVGCheckBox                 matlab.ui.control.CheckBox
        PlottingFunctionNameEditFieldLabel  matlab.ui.control.Label
        PlottingFunctionNameEditField   matlab.ui.control.EditField
        NewStaticPlottingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewStaticPlottingFunctionProjectLevelButton  matlab.ui.control.Button
        XSubsetDropDown                 matlab.ui.control.DropDown
        DropDown2_2                     matlab.ui.control.DropDown
        DropDown2_3                     matlab.ui.control.DropDown
        FrameNumbersCheckBox            matlab.ui.control.CheckBox
        SavingPlotToEditFieldLabel      matlab.ui.control.Label
        SavingPlotToEditField           matlab.ui.control.EditField
        OpenspecifyTrialsButton_2       matlab.ui.control.Button
        XVarSubsetLabel                 matlab.ui.control.Label
        YVarSubsetLabel                 matlab.ui.control.Label
        ZVarSubsetLabel                 matlab.ui.control.Label
        FrameIntervalEditFieldLabel     matlab.ui.control.Label
        FrameIntervalEditField          matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        SpeedEditField                  matlab.ui.control.NumericEditField
        MovieCheckBox                   matlab.ui.control.CheckBox
        DataRangeMethodEditFieldLabel   matlab.ui.control.Label
        DataRangeMethodEditField        matlab.ui.control.NumericEditField
        CheckBox_11                     matlab.ui.control.CheckBox
        NewMovieFunctionSubjectLevelButton  matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Import
        projectName % Project name from projectStruct.Info.ProjectName
        ProcessingPath % Path to processing functions
        DataPath % Path to all data (folder containing Subject Data folder)
        % Process
        SetupGroupNum % Number identifying one set of functions
        CurrGroupFcnNames % Function names for one individual set of functions.
        procGroup % All processing function names, split by group.
        RunGroupNum % The current group number. Run tab in Process current selection.
        numRunTabs % Number of times switched over to the Run tab. Used for keeping the same args as in the dropdown.
        groupArgs % The function arguments split by group
        % Plot
        plotFuncName % Name of the current plotting function to run
        PlotMult % Checkbox to plot multiple trials on one plot
        SaveFIG % Checkbox to save plot as .fig
        SavePNG % Checkbox to save plot as .png
        SaveTransPNG % Checkbox to save plot as transparent .png
        SaveSVG % Checkbox to save plot as .svg
        SubfolderName % Plot saving subfolder name (within root folder)
        RootSaveFolder % The root folder to save all figures
        PlotFrameNumbers % Plot timeseries using frame numbers
        XPlotLimsSubset % The subset of the variable of interest used to set the X axis limits ('all','subject','condition','subjCond','each')
        YPlotLimsSubset % The subset of the variable of interest used to set the Y axis limits ('all','subject','condition','subjCond','each')
        ZPlotLimsSubset % The subset of the variable of interest used to set the Z axis limits ('all','subject','condition','subjCond','each')
        Movie % Checkbox to plot a movie or static plot
        DataRangeMethod % Number for the method to plot a subset of the data. Leave as 0 to plot everything.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import
            app.DataPath='';
            app.ProcessingPath='';
            userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
            documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
            fileName='allProjects_ProjectNamesPaths.txt';
            cd(documentsFolderPath);
            projectCount=0;
            if exist(fileName,'file')==2 % Previously existing file
                A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                for i=1:length(A)
                    if length(A{i})>14 && isequal(A{i}(1:14),'Project Name: ') % Project name already exists, look for project path & logsheet path
                        projectCount=projectCount+1;
                        projectList{projectCount,1}=A{i}(15:end); % Get all of the project names in the document.
                    end
                end
                app.DropDown2_4.Items=projectList; % Sets the contents of the drop down list.
                app.ProjectNameEditField.Value=projectList{1}; % Set the project name.
                app.ProjectNameEditFieldValueChanged; % Run the callback to propagate the project-specific fields.
            end
            app.SetupGroupNum=0; % Initialize group number to be 0.
            app.CurrGroupFcnNames{1}=''; % Initialize current group function names to be empty char.
            app.RedoCheckBox.Value=0; % Initialize Redo to be unchecked.
            evalin('base','projectStruct.Info.Flags.Import.Redo=0;');
            app.AddDataTypesCheckBox.Value=0; % AddDataTypes begins unchecked.
            evalin('base','projectStruct.Info.Flags.Import.AddDataTypes=0;');
            app.UpdateMetadataOnlyCheckBox.Value=0; % UpdateMetadata begins unchecked.
            evalin('base','projectStruct.Info.Flags.Import.UpdateMetadata=0;');
            app.ReloadExistingDataCheckBox.Value=0; % ReloadExistingData begins unchecked.
            evalin('base','projectStruct.Info.Flags.Import.ReloadExistingData=0;');
            % Process
            app.numRunTabs=0; % Initialize the number of times on the run tab to be 0.
            % Plot
            app.MultipleTrialsCheckBox.Value=0; % Multiple trials begins unchecked
            evalin('base','projectStruct.Info.Flags.Plot.PlotMult=0;');
            app.SaveFigCheckBox.Value=0; % Save fig begins unchecked
            app.SaveFIG=0;
            evalin('base','projectStruct.Info.Flags.Plot.SaveFIG=0;');
            app.SavePNGCheckBox.Value=0; % Save png begins unchecked
            app.SavePNG=0;
            evalin('base','projectStruct.Info.Flags.Plot.SavePNG=0;');
            app.SavetransparentPNGCheckBox.Value=0; % Save transparent png begins unchecked
            app.SaveTransPNG=0;
            evalin('base','projectStruct.Info.Flags.Plot.SaveTransPNG=0;');
            app.SaveSVGCheckBox.Value=0; % Save svg begins unchecked
            app.SaveSVG=0;
            evalin('base','projectStruct.Info.Flags.Plot.SaveSVG=0;');
            app.FrameNumbersCheckBox.Value=0; % By default plots using time, not frame numbers
            app.PlotFrameNumbers=0;
            evalin('base','projectStruct.Info.Flags.Plot.PlotFrameNumbers=0;');
            app.XPlotLimsSubset='All';
            app.YPlotLimsSubset='All';
            app.ZPlotLimsSubset='All';
            app.MovieCheckBox.Value=0;
            evalin('base','projectStruct.Info.Flags.Plot.Movie=0;');
            app.Movie=0; % By default plots static plots, not movies.
            app.FrameIntervalEditField.Visible=0;
            app.SpeedEditField.Visible=0;
            app.DataRangeMethod=0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Are You Sure You Want to Close?','Confirm Close',...
                'Icon','warning');
            if isequal(selection,'OK')
                delete(app);
            end
        end

        % Value changed function: DropDown2_4
        function DropDown2_4ValueChanged(app, event)
            value = app.DropDown2_4.Value;
            app.ProjectNameEditField.Value=value; % Set the project name field to be the current selection.
            app.ProjectNameEditFieldValueChanged; % Run the ProjectNameEditFieldValueChanged callback to make that selection propagate to the rest of the project-specific fields.
            app.RunGroupNum=1; % Reset the run group number when choosing a new project.
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;
            if isempty(value)
                return; % Do nothing if the value is empty
            end
            if isequal(value(end),char(13))
                value=value(1:end-1);
            end
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectName=tempName;');
            app.projectName=value;
            % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
            userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
            documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
            fileName='allProjects_ProjectNamesPaths.txt';
            cd(documentsFolderPath);
            alreadyDone=0;
            if exist(fileName,'file')==2 % Previously existing file
                A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                for i=1:length(A)
                    if isempty(A{i})
                        continue;
                    end
                    if isequal(A{i}(end),char(13))
                        A{i}=A{i}(1:end-1);
                    end
                    if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                        alreadyDone=1;
                        if length(A)>=i+1 && ~isempty(A{i}) % Line exists and is not empty
                            app.LogsheetPathEditField.Value=A{i+1}(16:end);
                            assignin('base','logsheetPath',app.LogsheetPathEditField.Value);
                            evalin('base','projectStruct.Info.LogsheetPath=logsheetPath;');
                        end
                        if length(A)>=i+2 && ~isempty(A{i})
                            app.ProjectDataPathEditField.Value=A{i+2}(20:end);
                            assignin('base','dataPath',app.ProjectDataPathEditField.Value);
                            evalin('base','projectStruct.Info.DataPath=dataPath;');
                            app.DataPath=app.ProjectDataPathEditField.Value;
                        end
                        if length(A)>=i+3 && ~isempty(A{i})
                            app.ProjectProcessingPathEditField.Value=A{i+3}(26:end);
                            assignin('base','processingPath',app.ProjectProcessingPathEditField.Value);
                            evalin('base','projectStruct.Info.ProcessingPath=processingPath;');
                            app.ProcessingPath=app.ProjectProcessingPathEditField.Value;
                        end
                        if length(A)>=i+4 && ~isempty(A{i})
                            app.RootSaveFolderEditField.Value=A{i+4}(22:end);
                            assignin('base','rootSavePlotPath',app.RootSaveFolderEditField.Value);
                            evalin('base','projectStruct.Info.RootSavePlotPath=rootSavePlotPath;');
                            app.RootSaveFolder=app.RootSaveFolderEditField.Value;
                        end
                        break;
                    end
                end
                if alreadyDone==0
                    for i=length(A):-1:1
                        appendLine=length(A);
                        if isequal(A{i},'') && ~isequal(A{i-1},'')
                            appendLine=i; % Remove all of the trailing newline chars
                            break;
                        end
                    end
                    A{appendLine+1}=['Project Name: ' app.projectName]; % Store the project name
                    app.DropDown2_4.Items=[app.DropDown2_4.Items {app.projectName}]; % Add this new project name to the drop down list
                end
            else
                A{1}=['Project Name: ' app.projectName]; % New file
            end
            if alreadyDone==0
                fid=fopen([documentsFolderPath '\' fileName],'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
            end
            if alreadyDone==1
                if ~isempty(app.ProcessingPath)
                    cd(app.ProjectProcessingPathEditField.Value);
                end
            end
            addpath('SpecifyPaths'); % Add the SpecifyPaths subfolder to the path.
            addpath('SpecifyTrials'); % Add the SpecifyTrials subfolder to the path.
            addpath('Import');
            addpath('Process');
            addpath('Plot');
            % Run the SpecifyPaths function if it exists.
            if exist(char(["specifyPaths_" app.projectName ".m"]),'file')
                feval(char(["specifyPaths_" app.projectName ".m"]));
            end
            % If processing functions file already exists for this project, get the number of functions
            fileName=['functionNames_' app.projectName '.txt'];
            if exist(fileName,'file')==2
                A=regexp(fileread(fileName),'\n','split');
                for i=1:length(A)
                    if isempty(A{i}) || (length(A{i})==1 && isequal(double(A{i}),13))
                        continue; % Skip empty lines
                    end
                    if isequal(A{i}(1:5),'Group')
                        funcNum=0;
                        if isequal(A{i}(8),':')
                            RunGroupNumber=str2double(A{i}(7)); % Single digit number
                        else
                            RunGroupNumber=str2double(A{i}(7:8)); % Two digit number
                        end
                    else % Not a Group number and not empty
                        funcNum=funcNum+1;
                        parenIdx=find(ismember(A{i},'(')==1,1,'first'); % Get first idx of open parentheses in this line.
                        funcName=A{i}(1:parenIdx-1); % Function name
                        args=A{i}(parenIdx+1:end-1); % Get all args, but no parentheses
                        app.procGroup(RunGroupNumber).procFunc(funcNum).Name=funcName; % Store the function name from file.
                        app.procGroup(RunGroupNumber).procFunc(funcNum).Redo=0; % Initialize Redo to be 0.
                        a=regexp(args,', ','split'); % Split the args by comma + space.
                        app.procGroup(RunGroupNumber).procFunc(funcNum).Args=a; % Store the function args from file.
                    end
                end
            end
            if ~isequal(app.DataPath,'')
                cd(app.DataPath);
            end
        end

        % Value changed function: LogsheetPathEditField
        function LogsheetPathEditFieldValueChanged(app, event)
            value = app.LogsheetPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.LogsheetPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                currCD=cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+1}=['Logsheet Path: ' value];
                            break;
                        end
                    end
                else
                    A{2}=['Logsheet Path: ' value];
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
                cd(currCD);
            end
        end

        % Value changed function: ProjectDataPathEditField
        function ProjectDataPathEditFieldValueChanged(app, event)
            value = app.ProjectDataPathEditField.Value;
            app.DataPath=value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.DataPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+2}=['Project Data Path: ' value];
                            break;
                        end
                    end
                else
                    A{3}=['Project Data Path: ' value]; % New file
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
            end
        end

        % Value changed function: ProjectProcessingPathEditField
        function ProjectProcessingPathEditFieldValueChanged(app, event)
            value = app.ProjectProcessingPathEditField.Value;
            if isempty(value)
                return;
            end
            app.ProcessingPath=value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProcessingPath=tempName;');
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+3}=['Project Processing Path: ' value];
                            break;
                        end
                    end
                else
                    A{4}=['Project Processing Path: ' value]; % New file
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
            end
            currCD=cd(value);
            addpath('SpecifyPaths'); % Add the SpecifyPaths subfolder to the path.
            addpath('SpecifyTrials'); % Add the SpecifyTrials subfolder to the path.
            addpath('Import');
            addpath('Process');
            addpath('Plot');
            cd(currCD);
        end

        % Value changed function: RedoCheckBox
        function RedoCheckBoxValueChanged(app, event)
            value = app.RedoCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Import.Redo=tempCheckbox;');
        end

        % Value changed function: AddDataTypesCheckBox
        function AddDataTypesCheckBoxValueChanged(app, event)
            value = app.AddDataTypesCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Import.AddDataTypes=tempCheckbox;');
        end

        % Value changed function: UpdateMetadataOnlyCheckBox
        function UpdateMetadataOnlyCheckBoxValueChanged(app, event)
            value = app.UpdateMetadataOnlyCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Import.UpdateMetadata=tempCheckbox;');
        end

        % Value changed function: ReloadExistingDataCheckBox
        function ReloadExistingDataCheckBoxValueChanged(app, event)
            value = app.ReloadExistingDataCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Import.ReloadExistingData=tempCheckbox;');
        end

        % Button pushed function: 
        % OpenspecifyTrials_importProjectButton
        function OpenspecifyTrials_importProjectButtonPushed(app, event)
            % Open the specifyTrials function for this project's importProject
            cd(app.DataPath)
            if ~isfolder('SpecifyTrials')
                mkdir('SpecifyTrials');
            end
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            currName=['specifyTrials_' app.projectName '_importProject.m'];
            alrdyDone=0;
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    if isequal(listing(i).name,currName) % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                copyfile('specifyTrials_Template.m',currName); % Copy the template specifyTrials file
                A=regexp(fileread(currName),'\n','split'); % Get contents of new file, each line one cell element
                A{1}=['function [inclStruct]=specifyTrials_' app.projectName '_importProject(logsheetPath,projectPath,projectName)']; % Change the first line to the new function name.
                fid=fopen(currName,'w'); % Open the file.
                fprintf(fid,'%s\n',A{:}); % Rewrite the file.
                fclose(fid); % Close the file.
            end
            edit(currName); % Open the file to edit.
            cd(currCD);
        end

        % Button pushed function: OpenimportSettingsButton
        function OpenimportSettingsButtonPushed(app, event)
            if evalin('base',"exist(['importSettings' projectStruct.Info.ProjectName]);")==2 % Check if file already exists.
                evalin('base',"edit(['importSettings' projectStruct.Info.ProjectName]);"); % Open file. Never overwrites an existing file.
            else
                % Make & format the file if it does not already exist (based on template).
                evalin('base',"currName=['importSettings' projectStruct.Info.ProjectName '.m'];");
                evalin('base',"copyfile('importSettingsTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [ProjHelper,dataTypes,segment]=' currName(1:end-2) '(subjectListInStruct,markerName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value);
            addpath(genpath(app.ProjectProcessingPathEditField.Value)); % Add processing code folder and its subfunctions to the path.
            % Re-initialize project info if they don't exist
            assignin('base','projectName',app.ProjectNameEditField.Value);
            assignin('base','logsheetPath',app.LogsheetPathEditField.Value);
            assignin('base','dataPath',app.ProjectDataPathEditField.Value);
            assignin('base','processingPath',app.ProjectProcessingPathEditField.Value);
            evalin('base','projectStruct.Info.ProjectName=projectName;');
            evalin('base','projectStruct.Info.LogsheetPath=logsheetPath;');
            evalin('base','projectStruct.Info.DataPath=dataPath;');
            evalin('base','projectStruct.Info.ProcessingPath=processingPath;');
            % Re-initialize flags if they don't exist.
            assignin('base','importchkbox',app.RedoCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Import.Redo=importchkbox;');
            assignin('base','importchkbox',app.AddDataTypesCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Import.AddDataTypes=importchkbox;');
            assignin('base','importchkbox',app.UpdateMetadataOnlyCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Import.UpdateMetadata=importchkbox;');
            assignin('base','importchkbox',app.ReloadExistingDataCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Import.ReloadExistingData=importchkbox;');
            tic;
            evalin('base','projectStruct=importProject(projectStruct);'); % Run the import.
            toc;
            % Refresh the values of the checkboxes (in case projectStruct was cleared)
            assignin('base','importchckbox',app.MultipleTrialsCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.PlotMult=importchckbox;');
            assignin('base','importchckbox',app.SaveFigCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveFIG=importchckbox;');
            assignin('base','importchckbox',app.SavePNGCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.SavePNG=importchckbox;');
            assignin('base','importchckbox',app.SavetransparentPNGCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveTransPNG=importchckbox;');
            assignin('base','importchckbox',app.SaveSVGCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveSVG=importchckbox;');
            assignin('base','importchckbox',app.FrameNumbersCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.PlotFrameNumbers=importchkbox;');
            assignin('base','importchckbox',app.MovieCheckBox.Value);
            evalin('base','projectStruct.Info.Flags.Plot.Movie=importchckbox;');
            % Refresh the path to save the current plotting function
            assignin('base','saveFigLoc',app.SavingPlotToEditField.Value);
            evalin('base','projectStruct.Info.SaveFigLoc=saveFigLoc;');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % If there's a function names file already, load the function names into app.allFcnNames when going to Process tab
            if isempty(app.projectName)
                disp('PROJECT NAME MISSING!!!');
            end
            if ~isequal(selectedTab.Title,'Process')
                return;
            end
            %             currName=['functionNames_' app.projectName '.txt'];
            %             if exist(currName,'file')~=2 % Check that a functionNames file exists.
            %                 return;
            %             end
            %             A=regexp(fileread(currName),'\n','split'); % Read the file.
            %             for i=1:length(A)
            %                 if length(A{i})>5 && isequal(A{i}(1:5),'Group')
            %                     app.SetupGroupNum=str2double(A{i}(7));
            %                     funcCount=0;
            %                 elseif ~isequal(A{i},'')
            %                     funcCount=funcCount+1;
            %                     fcnName=regexp(A{i},'(','split');
            %                     fcnName=char(fcnName{1});
            %                     app.procGroup(app.SetupGroupNum).procFunc(funcCount).Name=fcnName;
            %                 end
            %             end
        end

        % Value changed function: FunctionNamesTextArea
        function FunctionNamesTextAreaValueChanged(app, event)
            value = app.FunctionNamesTextArea.Value;
            assignin('base','fcnNames',value);
            app.CurrGroupFcnNames=value;
            if isempty(value{1})
                disp('Empty Staged Function List'); % Display the emptiness
            else
                disp('Functions Staged To Save To Current Group Changed To:');
                for i=1:length(value)
                    disp(value{i});
                end
            end
            disp('');
        end

        % Button pushed function: 
        % AddFunctionstoGroupSavetoFileButton
        function AddFunctionstoGroupSavetoFileButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value); % CD to the project path.
            if app.SetupGroupNum==0 || isequal(app.CurrGroupFcnNames{1},'')
                disp('NEED TO SET UP A GROUP FIRST!');
                beep;
                return;
            end
            % Save the group number & function names to file.
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2
                f=fopen(currName,'w'); % Create or open file.
                fclose(f); % Close file, because fileread doesn't need it.
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file line by line
            fileLength=length(A); % Number of lines already in the file.
            sameGroupExists=0; % Initialize that the group does not exist in this file.
            higherGroupExists=0;
            lowerGroupExists=0;
            if fileLength==1 % Starting a new file.
                lineNum=0; % Ensure that writing to the file begins at the first line.
                B={};
                for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                    if i==1 % Add group number at beginning of group
                        lineNum=lineNum+1;
                        B{lineNum}=['Group ' num2str(app.SetupGroupNum) ':'];
                    end
                    lineNum=lineNum+1;
                    B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                end
            else
                % Check if overwriting a group or adding a group. If adding, ensure it goes at proper position.
                % Overwriting.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.SetupGroupNum<10 && isequal(str2double(A{i}(end-1)),app.SetupGroupNum)) || (app.SetupGroupNum>=10 && app.SetupGroupNum<100 && isequal(str2double(A{i}(end-2:end-1)),app.SetupGroupNum)))
                        sameGroupExists=1; % Group number found in the file.
                        startInsertLine=i; % Line number of the group number
                    end
                    if sameGroupExists==1 % Check for first empty line after the group
                        if isequal(A{i},'') || i==length(A)
                            lastLine=i; % Line number of the empty line after the group
                            break;
                        end
                    end
                end
                if sameGroupExists==1 % Replace a previously written group
                    % Copy over everything before the overwritten group
                    if startInsertLine>1
                        B=A(1:startInsertLine-1); % Ends with a '' just before the group name
                    else
                        B={};
                    end
                    % Overwrite the group. Check if function name already existed in the group. If so, don't overwrite it (because that would delete its args)
                    for i=1:length(app.procGroup(app.SetupGroupNum).procFunc)
                        prevExistFcnNames{i}=app.procGroup(app.SetupGroupNum).procFunc(i).Name;
                    end
                    lineNum=startInsertLine-1; % The line with a '' just before the group name
                    for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                        if i==1 % Add group number at beginning of group
                            lineNum=lineNum+1;
                            B{lineNum}=['Group ' num2str(app.SetupGroupNum) ':'];
                        end
                        lineNum=lineNum+1;
                        if any(contains(prevExistFcnNames,app.CurrGroupFcnNames{i})) % This function name already existed in this group
                            fcnNum=find(contains(prevExistFcnNames,app.CurrGroupFcnNames{i})==1,1,'first'); % Fails when there are multiple repetitions of the same function.
                            for j=1:length(app.procGroup(app.SetupGroupNum).procFunc(fcnNum).Args)
                                if j==1
                                    currArgs=app.procGroup(app.SetupGroupNum).procFunc(fcnNum).Args{j};
                                else
                                    currArgs=[currArgs ', ' app.procGroup(app.SetupGroupNum).procFunc(fcnNum).Args{j}];
                                end
                            end
                            B{lineNum}=[app.CurrGroupFcnNames{i} '(' currArgs ')']; % Don't overwrite the function arguments
                        else
                            B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                        end
                    end
                    % Add in the rest of the group function names, if any.
                    if lastLine<length(A) % Check if there are any more groups
                        B=horzcat(B,A(lastLine:end));
                    end
                elseif sameGroupExists==0
                    % Adding new group.
                    % Case 1: Group number lower than all existing groups (goes at start of file)
                    % Case 2: Group number in middle of all existing groups (goes in middle of file)
                    % Case 3: Group number larger than all existing groups (goes at end of file)
                    % Check for lower groups
                    for i=1:length(A)
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.SetupGroupNum<10 && str2double(A{i}(end-1))<app.SetupGroupNum) || (app.SetupGroupNum>=10 && app.SetupGroupNum<100 && str2double(A{i}(end-2:end-1))<app.SetupGroupNum))
                            lowerGroupExists=1; % Group number found in the file.
                        end
                        % Check for higher groups
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.SetupGroupNum<10 && str2double(A{i}(end-1))>app.SetupGroupNum) || (app.SetupGroupNum>=10 && app.SetupGroupNum<100 && str2double(A{i}(end-2:end-1))>app.SetupGroupNum))
                            higherGroupExists=1; % Group number found in the file.
                            firstLine=i; % Line number of first function of the higher group
                        end
                    end
                    % Case 1 and 3
                    if (higherGroupExists==1 && lowerGroupExists~=1) || (higherGroupExists~=1 && lowerGroupExists==1)
                        % Write the group to file
                        lineNum=0; % The line with a '' just before the group name
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.SetupGroupNum) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                        end
                        if higherGroupExists==1 && lowerGroupExists~=1 % Case 1
                            B{lineNum+1}='';
                            B=horzcat(B,A); % Append A to B because all groups are higher than the current.
                        elseif higherGroupExists~=1 && lowerGroupExists==1 % Case 3
                            appendLine=length(A); % By default, the last line.
                            for i=length(A):-1:1
                                if isequal(A{i},'') && ~isequal(A{i-1},'') % First newline after the last function name.
                                    appendLine=i;
                                    break;
                                end
                            end
                            B=horzcat(A(1:appendLine),B); % Append B to A because all groups are lower than the the current. Avoids including many newline characters at the end.
                        end
                    end
                    % Case 2
                    if higherGroupExists==1 && lowerGroupExists==1
                        B=A(1:firstLine-1); % Set B to be everything in A up to and including the empty line before the new group.
                        lineNum=firstLine-1; % The '' line before the higher group.
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.SetupGroupNum) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=[app.CurrGroupFcnNames{i} '()']; % Add each function name to its own line.
                        end
                        B=horzcat(B,A(firstLine-1:end)); % Append the rest of A to the file with the new group.
                    end
                end
            end
            % Update the stored list of function names
            if app.SetupGroupNum<=length(app.procGroup) % This group number already exists.
%                 app.procGroup(app.SetupGroupNum).procFunc()
                for i=1:length(app.procGroup(app.SetupGroupNum).procFunc)-length(app.CurrGroupFcnNames)
                    app.procGroup(app.SetupGroupNum).procFunc(length(app.CurrGroupFcnNames)+1)=[]; % Delete everything in existing function list after length of new function list
                end
            end
            % Store the current set of functions into the master list. Will overwrite the group.
            disp(['Functions Added to Group ' num2str(app.SetupGroupNum) ':']);
            for i=1:length(app.CurrGroupFcnNames)
                app.procGroup(app.SetupGroupNum).procFunc(i).Name=app.CurrGroupFcnNames{i};
                app.procGroup(app.SetupGroupNum).procFunc(i).Redo=0; % Initialize Redo to be 0 when changing the function list.
                disp(app.CurrGroupFcnNames{i});
            end
            disp('');
            fid=fopen(currName,'w');
            fprintf(fid,'%s\n',B{:});
            fclose(fid);
        end

        % Value changed function: FunctionGroupEditField
        function FunctionGroupEditFieldValueChanged(app, event)
            value = app.FunctionGroupEditField.Value;
            assignin('base','SetupGroupNum',value); % Assign the function group number specified to base.
            app.SetupGroupNum=value; % Store the function group number.
        end

        % Button pushed function: 
        % LoadFunctionNamesinGroupFromFileButton
        function LoadFunctionNamesinGroupFromFileButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value);
            currName=['functionNames_' app.projectName '.txt'];
            if isempty(app.projectName)
                disp('Forgot to specify project name!');
                return;
            end
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('No function names file for this project!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            sameGroupExists=0; % Initialize that the group does not exist in this file.
            for i=1:length(A)
                if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.SetupGroupNum<10 && isequal(str2double(A{i}(end-1)),app.SetupGroupNum)) || (app.SetupGroupNum>=10 && app.SetupGroupNum<100 && isequal(str2double(A{i}(end-2:end-1)),app.SetupGroupNum)))
                    sameGroupExists=1; % Group number found in the file.
                    firstFcnLine=i+1;
                end
                if sameGroupExists==1 % Check for first empty line after the group
                    if isequal(A{i},'')
                        lastFcnLine=i-1;
                        break;
                    elseif i==length(A)
                        lastFcnLine=i;
                    end
                end
            end
            if sameGroupExists==0
                disp('Wrong group number!');
                return;
            end
            % Put the function names into the text area
            currFcnNames=cell(lastFcnLine-firstFcnLine+1,1);
            fcnCount=0;
            for i=firstFcnLine:lastFcnLine
                fcnCount=fcnCount+1;
                currFcnName=regexp(A{i},'(','split');
                currFcnName=char(currFcnName{1});
                currFcnNames{fcnCount}=currFcnName;
                app.procGroup(app.SetupGroupNum).procFunc(fcnCount).Name=currFcnName; % Store function names.
            end
            app.FunctionNamesTextArea.Value=currFcnNames; % Display function names.
        end

        % Button pushed function: 
        % NewProcessingFunctionTrialLevelButton
        function NewProcessingFunctionTrialLevelButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Process_trialTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionSubjectLevelButton
        function NewProcessingFunctionSubjectLevelButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];");
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Process_subjectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,dataStruct,currSubTrialsList,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionProjectLevelButton
        function NewProcessingFunctionProjectLevelButtonPushed(app, event)
            cd(app.ProjectDataPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Process_projectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [projectStruct]=' currName(1:end-2) '(projectStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: OpenspecifyTrialsButton
        function OpenspecifyTrialsButtonPushed(app, event)
            if app.SetupGroupNum==0
                return;
            end
            evalin('base','cd(projectStruct.Info.ProcessingPath)'); % Set the current directory to the project folder.
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            evalin('base',"currName=['specifyTrials_' projectStruct.Info.ProjectName '_' num2str(SetupGroupNum) '.m'];");
            alrdyDone=0;
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    assignin('base','tempName',listing(i).name); % Assign the current file name to base.
                    if evalin('base','isequal(tempName,currName)') % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                evalin('base',char("copyfile('specifyTrials_Template.m',currName);")); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [inclStruct]=specifyTrials_' projectStruct.Info.ProjectName '_' num2str(SetupGroupNum) '(logsheetPath,processingPath,projectName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
            cd(currCD);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab; % Called when switching from Setup to Run
            if ~isequal(selectedTab.Title,'Run') % In the Run tab
                return;
            end
            if isequal(app.projectName(end),char(13))
                app.projectName=app.projectName(1:end-1);
            end
            app.numRunTabs=app.numRunTabs+1; % Increment the count of the number of times the Run tab has been accessed. Needs to reset each time the project name is reset.
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('No function names file for this project!');
                return;
            end
            % Iterate through the number of processing function groups to set up the drop down list.
            newMaxNumGroups=0; % For length of the drop down list
            projectCount=0; % Number of groups.
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            for i=1:length(A)
                if isempty(A{i})
                    continue;
                end
                if length(A{i})>=5 && isequal(A{i}(1:5),'Group')
                    funcCount=0; % Number of functions in this group.
                    if regexp(A{i}(end-2),'[0-9]')
                        currGroupNum=str2double(A{i}(end-2:end-1));
                    else
                        currGroupNum=str2double(A{i}(end-1));
                    end
                    projectCount=projectCount+1;
                    groupList{projectCount}=['Group ' num2str(projectCount)];
                else % This line is a function name.
                    funcCount=funcCount+1; % Function count in group
%                     app.procGroup(currGroupNum).procFunc(funcCount).Name=A{i}; % Add function name
                    if currGroupNum>newMaxNumGroups
                        newMaxNumGroups=currGroupNum;
                    end
                end
            end
            if ~exist('groupList','var')
                groupList{1}='NULL';
            end
            app.DropDown.Items=groupList; % Set dropdown list according to the number of groups.
            if ~isequal(groupList{1},'NULL')
                if app.numRunTabs==1 % If first time
                    app.RunGroupNum=str2double(groupList{1}(end)); % Group number
                else
                    % RunGroupNum maintains its prior value, and everything else should flow from that.
                end
            else
                app.RunGroupNum=1;
            end
            procGroupNum=0; % Initialize all of the Redo flags to be 0.
            currName=['functionNames_' app.projectName '.txt'];
            currGroupNames=''; % Initialize.
            if exist(currName,'file')==2
                A=regexp(fileread(currName),'\n','split'); % Read thes file.
                for i=1:length(A)
                    if isempty(A{i})
                        continue;
                    end
                    if isequal(double(A{i}(end)),13)
                        A{i}=A{i}(1:end-1);
                    end
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                        if procGroupNum~=0 && funcCount<length(app.procGroup(procGroupNum).procFunc) % After first group, remove all extra group names.
                            for j=funcCount+1:length(app.procGroup(procGroupNum).procFunc)
                                app.procGroup(procGroupNum).procFunc(j)=[]; % Delete everything in existing function list after length of new function list
                            end
                        end
                        funcCount=0;
                        if regexp(A{i}(end-2),'[0-9]')
                            procGroupNum=str2double(A{i}(end-2:end-1));
                        else
                            procGroupNum=str2double(A{i}(end-1));
                        end
                    elseif ~isempty(A{i}) % This line is a function name.
                        funcCount=funcCount+1;
                        parensIdx=strfind(A{i},'(');
                        openParensIdx=parensIdx(1); % First parens idx.
                        fcnName=A{i}(1:openParensIdx-1);
                        if length(A{i})>openParensIdx+1
                            argNames=regexp(A{i}(openParensIdx+1:end-1),', ','split'); % Omit beginning & ending parentheses
                            %                                 argNames=char(fcnName{2}(1:end-1)); % Omit the ending parantheses
                        else
                            argNames='';
                        end
                        %                             fcnName=char(fcnName{1});
                        if isempty(app.procGroup)
                            app.procGroup(procGroupNum).procFunc(funcCount).Redo=0;
                        end
                        if procGroupNum>length(app.procGroup) || funcCount>length(app.procGroup(procGroupNum).procFunc) || isempty(app.procGroup(procGroupNum).procFunc(funcCount).Redo)
                            app.procGroup(procGroupNum).procFunc(funcCount).Redo=0;
                        end
                        app.procGroup(procGroupNum).procFunc(funcCount).Name=fcnName; % Add function name
                        %                             a=regexp(argNames,', ','split');
                        app.procGroup(procGroupNum).procFunc(funcCount).Args=argNames; % List of arguments from file.
                        if ~iscell(app.procGroup(procGroupNum).procFunc(funcCount).Args)
                            app.procGroup(procGroupNum).procFunc(funcCount).Args={app.procGroup(procGroupNum).procFunc(funcCount).Args};
                        end
                    end
                end
            else
                currGroupNames='NULL';
            end
            if isempty(app.RunGroupNum)
                app.RunGroupNum=1;
            end
            for i=1:10 % Set the checkboxes visibility to match the current group.
                if i<=length(app.procGroup(app.RunGroupNum).procFunc) && ~isequal(currGroupNames,'NULL') % Check if this group exists.
                    app.(['CheckBox_' num2str(i)]).Value=double(app.procGroup(app.RunGroupNum).procFunc(i).Redo); % Set checkbox to most recent value.
                    app.(['CheckBox_' num2str(i)]).Visible='On';
                else
                    app.(['CheckBox_' num2str(i)]).Visible='Off';
                end
            end
            % Aggregate function names and args to display.
            currNames=cell(length(app.procGroup(app.RunGroupNum).procFunc),1);
            currArgs=cell(length(app.procGroup(app.RunGroupNum).procFunc),1);
            for i=1:length(app.procGroup(app.RunGroupNum).procFunc)
                currNames{i}=app.procGroup(app.RunGroupNum).procFunc(i).Name;
                currArgs{i}='';
                if ~isempty(app.procGroup(app.RunGroupNum).procFunc(i).Args{1})
                    for j=1:length(app.procGroup(app.RunGroupNum).procFunc(i).Args)
                        if j>1
                            currArgs{i}=[currArgs{i} ', ' app.procGroup(app.RunGroupNum).procFunc(i).Args{j}];
                        else
                            currArgs{i}=app.procGroup(app.RunGroupNum).procFunc(i).Args{j};
                        end
                    end
                else
                    % Get & display the default args list by running the functions with nargin=0.
                    if exist(app.procGroup(app.RunGroupNum).procFunc(i).Name,'file')~=2
                        disp(['Unrecognized function: ' app.procGroup(app.RunGroupNum).procFunc(i).Name]);
                        return;
                    end
                    procOut=setupFuncs(app.procGroup(app.RunGroupNum).procFunc(i),'Process');
                    
                    if isstring(procOut.Args)
                        currArgs{i}=char(procOut.Args);
                    end
                    currArgs{i}=['DEFAULT: ' currArgs{i}];
                end
            end
            app.FunctionNamesTextArea_2.Value=currNames;
            app.FunctionNamesTextArea_2.Editable=0;
            % Put function arguments into the FunctionArgsTextArea
            app.FunctionArgsTextArea.Value=currArgs;
            app.FunctionArgsTextArea.Editable=1;
            app.groupArgs{app.RunGroupNum}=currArgs;
            app.DropDown2.Items=currNames;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if ~isspace(value(end-1)) % 2 digits
                app.RunGroupNum=str2double(value(end-1:end));
            else
                app.RunGroupNum=str2double(value(end)); % Group number
            end            
            for i=1:10 % Iterate over all labels.
                app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                if i<=length(app.procGroup(app.RunGroupNum).procFunc) % Check if this group exists.
                    %                     app.(['Fcn' num2str(i) 'Label']).Visible='On';
                    %                     app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                    app.(['CheckBox_' num2str(i)]).Visible='On';
                    app.(['CheckBox_' num2str(i)]).Value=double(app.procGroup(app.RunGroupNum).procFunc(i).Redo); % Set the checkbox to the stored value.
                else
                    app.(['CheckBox_' num2str(i)]).Visible='Off';
                    %                     app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                end
            end
            currNames=cell(length(app.procGroup(app.RunGroupNum).procFunc),1);
            currArgs=cell(length(app.procGroup(app.RunGroupNum).procFunc),1);
            for i=1:length(app.procGroup(app.RunGroupNum).procFunc)
                currNames{i}=app.procGroup(app.RunGroupNum).procFunc(i).Name;
                currArgs{i}='';
                if ~isempty(app.procGroup(app.RunGroupNum).procFunc(i).Args{1})
                    for j=1:length(app.procGroup(app.RunGroupNum).procFunc(i).Args)
                        if j>1
                            currArgs{i}=[currArgs{i} ', ' app.procGroup(app.RunGroupNum).procFunc(i).Args{j}];
                        else
                            currArgs{i}=app.procGroup(app.RunGroupNum).procFunc(i).Args{j};
                        end
                    end
                else
                    % Get & display the default args list by running the functions with nargin=0.
                    if exist(app.procGroup(app.RunGroupNum).procFunc(i).Name,'file')~=2
                        disp(['Unrecognized function: ' app.procGroup(app.RunGroupNum).procFunc(i).Name]);
                        return;
                    end
                    procOut=setupFuncs(app.procGroup(app.RunGroupNum).procFunc(i),'Process');
                    
                    if isstring(procOut.Args)
                        currArgs{i}=char(procOut.Args);
                    end
                    currArgs{i}=['DEFAULT: ' currArgs{i}];
                end
            end
            app.FunctionNamesTextArea_2.Value=currNames;
            app.FunctionNamesTextArea_2.Editable=0;
            app.FunctionArgsTextArea.Editable=1;
            app.FunctionArgsTextArea.Value=currArgs;
            app.DropDown2.Items=currNames;
        end

        % Value changed function: FunctionArgsTextArea
        function FunctionArgsTextAreaValueChanged(app, event)
            value = app.FunctionArgsTextArea.Value;
            % When switching to the tab, should display the current group's args from file (if any)
            % When the value is changed, it should temporarily store the function args (but not save them yet).
            
            app.groupArgs{app.RunGroupNum}=value; % Prevents elements later down the list from reappearing after deletion.
        end

        % Button pushed function: SaveArgstoFileButton
        function SaveArgstoFileButtonPushed(app, event)
            % Save the current group's args to the functionNames file.
            cd(app.ProjectDataPathEditField.Value);
            currArgs=app.FunctionArgsTextArea.Value;
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('Missing function names file!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            funcCount=0;
            correctGroup=0;
            for i=1:length(A)                
                if length(A{i})>5 && isequal(A{i}(1:5),'Group') && ((isequal(str2double(A{i}(7)),app.RunGroupNum) && isequal(str2double(A{i}(end-3)),' ')) || (isequal(str2double(A{i}(7:8)),app.RunGroupNum) && ~isequal(str2double(A{i}(end-3)),' ')))
                    correctGroup=1; % Indicates that this is the correct group
                elseif ~isequal(A{i},'') && correctGroup==1 % This line is a function name.
                    funcCount=funcCount+1;
                    allParensIdx=strfind(A{i},'(');
                    fcnName=A{i}(1:allParensIdx(1)-1);
                    if funcCount<=length(currArgs)
                        A{i}=strcat([fcnName '(' currArgs{funcCount} ')']); % Add args to the file.
                        a=regexp(currArgs{funcCount},', ','split');
                        app.procGroup(app.RunGroupNum).procFunc(funcCount).Args=a; % Add args to the procGroup variable.
                    end
                elseif isequal(A{i},'') && correctGroup==1
                    break; % Stop processing after one group.
                end
            end
            fid=fopen(currName,'w');
            fprintf(fid,'%s\n',A{:});
            fclose(fid);
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            app.procGroup(app.RunGroupNum).procFunc(1).Redo=value;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.procGroup(app.RunGroupNum).procFunc(2).Redo=value;
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.procGroup(app.RunGroupNum).procFunc(3).Redo=value;
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            app.procGroup(app.RunGroupNum).procFunc(4).Redo=value;
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            app.procGroup(app.RunGroupNum).procFunc(5).Redo=value;
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            app.procGroup(app.RunGroupNum).procFunc(6).Redo=value;
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            app.procGroup(app.RunGroupNum).procFunc(7).Redo=value;
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            app.procGroup(app.RunGroupNum).procFunc(8).Redo=value;
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            app.procGroup(app.RunGroupNum).procFunc(9).Redo=value;
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            app.procGroup(app.RunGroupNum).procFunc(10).Redo=value;
        end

        % Button pushed function: OpenFunctionButton
        function OpenFunctionButtonPushed(app, event)
            currCD=cd(app.ProcessingPath);
            cd('Process');
            edit(app.DropDown2.Value); % Open the function of interest.
            cd(currCD);
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
        end

        % Button pushed function: RunGroupButton
        function RunGroupButtonPushed(app, event)
            % Run the group with the function names and args saved in the file, NOT in the GUI (if different)
            cd(app.ProjectDataPathEditField.Value);
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('Missing function names file!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            funcCount=0;
            for i=1:length(A)
                if isempty(A{i})
                    continue;
                end
                if length(A{i})>5 && isequal(A{i}(1:5),'Group') && isequal(str2double(A{i}(7)),app.RunGroupNum)
                    
                elseif ~isequal(A{i},'') && isequal(str2double(A{i}(7)),app.RunGroupNum)
                    funcCount=funcCount+1;
                    openParenIdx=find(A{i}=='(',1,'first');
                    fcnName=A{i}(1:openParenIdx-1);
                    if openParenIdx+1<length(A{i})
                        argNames=A{i}(openParenIdx+1:end-1); % Omit the ending parentheses
                    else
                        argNames='';
                    end
                    app.procGroup(app.RunGroupNum).procFunc(funcCount).Name=fcnName;
                    a=regexp(argNames,', ','split');
                    app.procGroup(app.RunGroupNum).procFunc(funcCount).Args=a;
                else
                    break;
                end
            end
            for i=1:length(app.procGroup(app.RunGroupNum).procFunc)
                currFuncs(i)=app.procGroup(app.RunGroupNum).procFunc(i);
            end
            for i=1:length(app.procGroup(app.RunGroupNum).procFunc)
                currFuncs(i).SpecifyTrialsCount=app.RunGroupNum;
            end
            assignin('base','procFunc',currFuncs); % Assign the processing function names & metadata to the base workspace.
            evalin('base','projectStruct.Info.Flags.Org=0;'); % Ensure that trial names are listed by subject, not by conditions.
            dbstop if caught error;
            tic;
            evalin('base','[projectStruct]=processData_GUI(projectStruct,procFunc);'); % Run the data processing.
            toc;
            dbclear if caught error;
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            % Run the group with the function names and args saved in the file, NOT in the GUI (if different)
            cd(app.ProjectDataPathEditField.Value);
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('Missing function names file!');
                return;
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            funcCount=0;
            for i=1:length(A)
                if isempty(A{i})
                    continue;
                end
                if length(A{i})>5 && isequal(A{i}(1:5),'Group') && isequal(str2double(A{i}(7)),app.RunGroupNum)
                    
                elseif ~isequal(A{i},'') && isequal(str2double(A{i}(7)),app.RunGroupNum)
                    funcCount=funcCount+1;
                    openParenIdx=find(A{i}=='(',1,'first');
                    fcnName=A{i}(1:openParenIdx-1);
                    if openParenIdx+1<length(A{i})
                        argNames=A{i}(openParenIdx+1:end-1); % Omit the ending parentheses
                    else
                        argNames='';
                    end
                    app.procGroup(app.RunGroupNum).procFunc(funcCount).Name=fcnName;
                    a=regexp(argNames,', ','split');
                    app.procGroup(app.RunGroupNum).procFunc(funcCount).Args=a;
                else
                    break;
                end
            end
            funcCount=0;
            for i=1:length(app.procGroup)
                for j=1:length(app.procGroup(app.RunGroupNum).procFunc)
                    funcCount=funcCount+1;
                    currFuncs(funcCount)=app.procGroup(i).procFunc(j);
                end
            end
            funcCount=0;
            for i=1:length(app.procGroup)
                for j=1:length(app.procGroup(app.RunGroupNum).procFunc)
                    funcCount=funcCount+1;
                    currFuncs(funcCount).SpecifyTrialsCount=i;
                end
            end
            assignin('base','procFunc',currFuncs); % Assign the processing function names & metadata to the base workspace.
            evalin('base','projectStruct.Info.Flags.Org=0;'); % Ensure that trial names are listed by subject, not by conditions.
            dbstop if caught error;
            tic;
            evalin('base','[projectStruct]=processData_GUI(projectStruct,procFunc);'); % Run the data processing.
            toc;
            dbclear if caught error;
        end

        % Value changed function: RootSaveFolderEditField
        function RootSaveFolderEditFieldValueChanged(app, event)
            value = app.RootSaveFolderEditField.Value;
            assignin('base','tempName',value);
            app.RootSaveFolder=value;
            if ~isempty(app.projectName)
                % If the logsheet or processing folder values have been changed, save them to a file for easy later recall.
                userProfile=getenv('USERPROFILE'); % Get the name of the user who logged in to the computer.
                documentsFolderPath=sprintf('%s\\Documents', userProfile); % Create a string to the "Documents" folder of this Windows user
                fileName='allProjects_ProjectNamesPaths.txt';
                currCD=cd(documentsFolderPath);
                if exist(fileName,'file')==2 % Previously existing file
                    A=regexp(fileread(fileName),'\n','split'); % Read in the file.
                    for i=1:length(A)
                        if isequal(A{i},['Project Name: ' app.projectName]) % Project name already exists, look for project path & logsheet path
                            A{i+4}=['Save Plot Root Path: ' value];
                            break;
                        end
                    end
                else
                    A{5}=['Save Plot Root Path: ' value];
                end
                fid=fopen(fileName,'w');
                fprintf(fid,'%s\n',A{:});
                fclose(fid);
                cd(currCD);
            end
            if isempty(app.SubfolderName)
                app.SavingPlotToEditField.Value=value;
            else
                app.SavingPlotToEditField.Value=[value app.SubfolderName];
                evalin('base','projectStruct.Info.SavePlotPath=tempName;');
                assignin('base','saveFigLoc',app.SavingPlotToEditField.Value);
                evalin('base','projectStruct.Info.SaveFigLoc=saveFigLoc;');
            end
        end

        % Value changed function: CurrentSaveSubfolderEditField
        function CurrentSaveSubfolderEditFieldValueChanged(app, event)
            value = app.CurrentSaveSubfolderEditField.Value;
            app.SubfolderName=value;
            if ~isempty(app.RootSaveFolder)
                app.SavingPlotToEditField.Value=[app.RootSaveFolder value];
                assignin('base','saveFigLoc',app.SavingPlotToEditField.Value);
                evalin('base','projectStruct.Info.SaveFigLoc=saveFigLoc;');
            end
        end

        % Button pushed function: NewMovieFunctionSubjectLevelButton
        function NewMovieFunctionSubjectLevelButtonPushed(app, event)
            cd(app.ProjectProcessingPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Plot_movieTemplate.m',['Plot\Plot_' currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(['Plot\Plot_' currName]),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=Plot_' currName(1:end-2) '(projInfo,dataStruct,currSubTrialsList,plotFunc)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(['Plot\Plot_' currName],'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base',"edit(['Plot\Plot_' currName]);"); % Open the file to edit.
        end

        % Button pushed function: 
        % NewStaticPlottingFunctionSubjectLevelButton
        function NewStaticPlottingFunctionSubjectLevelButtonPushed(app, event)
            cd(app.ProjectProcessingPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Plot_subjectTemplate.m',['Plot\Plot_' currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(['Plot\Plot_' currName]),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=Plot_' currName(1:end-2) '(projInfo,dataStruct,currSubTrialsList,plotFunc)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(['Plot\Plot_' currName],'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base',"edit(['Plot\Plot_' currName]);"); % Open the file to edit.
        end

        % Button pushed function: 
        % NewStaticPlottingFunctionProjectLevelButton
        function NewStaticPlottingFunctionProjectLevelButtonPushed(app, event)
            cd(app.ProjectProcessingPathEditField.Value);
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            if evalin('base','isempty(tempName)')
                return;
            end
            evalin('base','tempName=tempName{1};');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                addpath('Templates');
                evalin('base',"copyfile('Templates\Plot_projectTemplate.m',['Plot\Plot_' currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(['Plot\Plot_' currName]),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=Plot_' currName(1:end-2) '(projectStruct,plotFunc)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(['Plot\Plot_' currName],'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base',"edit(['Plot\Plot_' currName]);"); % Open the file to edit.
        end

        % Value changed function: PlottingFunctionNameEditField
        function PlottingFunctionNameEditFieldValueChanged(app, event)
            value = app.PlottingFunctionNameEditField.Value;
            app.plotFuncName=value;
            assignin('base','plotFuncName',app.plotFuncName); % Assign the plotting function name in to the base workspace
            evalin('base','plotFunc.Name=plotFuncName;'); % Add the plotting function name to the projectStruct
        end

        % Value changed function: MultipleTrialsCheckBox
        function MultipleTrialsCheckBoxValueChanged(app, event)
            value = app.MultipleTrialsCheckBox.Value;
            app.PlotMult=value;
            assignin('base','chckbox',value);
            evalin('base','projectStruct.Info.Flags.Plot.PlotMult=chckbox;');
        end

        % Value changed function: SaveFigCheckBox
        function SaveFigCheckBoxValueChanged(app, event)
            value = app.SaveFigCheckBox.Value;
            app.SaveFIG=value;
            assignin('base','saveFIG',value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveFIG=saveFIG;');
        end

        % Value changed function: SavePNGCheckBox
        function SavePNGCheckBoxValueChanged(app, event)
            value = app.SavePNGCheckBox.Value;
            app.SavePNG=value;
            assignin('base','savePNG',value);
            evalin('base','projectStruct.Info.Flags.Plot.SavePNG=savePNG;');
        end

        % Value changed function: SavetransparentPNGCheckBox
        function SavetransparentPNGCheckBoxValueChanged(app, event)
            value = app.SavetransparentPNGCheckBox.Value;
            app.SaveTransPNG=value;
            assignin('base','saveTransPNG',value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveTransPNG=saveTransPNG;');
        end

        % Value changed function: SaveSVGCheckBox
        function SaveSVGCheckBoxValueChanged(app, event)
            value = app.SaveSVGCheckBox.Value;
            app.SaveSVG=value;
            assignin('base','saveSVG',value);
            evalin('base','projectStruct.Info.Flags.Plot.SaveSVG=saveSVG;');
        end

        % Value changed function: FrameNumbersCheckBox
        function FrameNumbersCheckBoxValueChanged(app, event)
            value = app.FrameNumbersCheckBox.Value;
            app.PlotFrameNumbers=value;
        end

        % Value changed function: XSubsetDropDown
        function XSubsetDropDownValueChanged(app, event)
            value = app.XSubsetDropDown.Value;
            app.XPlotLimsSubset=value;
            assignin('base','xSubset',value);
            evalin('base','plotFunc.Subset.X=xSubset;');
        end

        % Value changed function: DropDown2_2
        function YSubsetDropDownValueChanged(app, event)
            value = app.DropDown2_2.Value;
            app.YPlotLimsSubset=value;
            assignin('base','ySubset',value);
            evalin('base','plotFunc.Subset.Y=ySubset;');
        end

        % Value changed function: DropDown2_3
        function ZSubsetDropDownValueChanged(app, event)
            value = app.DropDown2_3.Value;
            app.ZPlotLimsSubset=value;
            assignin('base','zSubset',value);
            evalin('base','plotFunc.Subset.Z=zSubset;');
        end

        % Button pushed function: OpenspecifyTrialsButton_2
        function OpenspecifyTrialsButton_2Pushed(app, event)
            if isempty(app.plotFuncName) || isempty(app.projectName)
                return;
            end
            pathName=['specifyTrials_' app.projectName '_' app.plotFuncName '.m'];
            cd(app.DataPath)
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    if isequal(listing(i).name,pathName) % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                copyfile('specifyTrials_Template.m',pathName); % Copy the template specifyTrials file
                A=regexp(fileread(pathName),'\n','split'); % Get contents of new file, each line one cell element
                A{1}=['function [inclStruct]=specifyTrials_' app.projectName '_' app.plotFuncName '(logsheetPath,processingPath,projectName)']; % Change the first line to the new function name.
                fid=fopen(pathName,'w'); % Open the file.
                fprintf(fid,'%s\n',A{:}); % Rewrite the file.
                fclose(fid); % Close the file.
            end
            edit(pathName); % Open the file to edit.
            cd(currCD);
        end

        % Value changed function: MovieCheckBox
        function MovieCheckBoxValueChanged(app, event)
            value = app.MovieCheckBox.Value;
            app.Movie=value;
            if app.Movie==1
                app.FrameIntervalEditField.Visible=1;
                app.SpeedEditField.Visible=1;
            else
                app.FrameIntervalEditField.Visible=0;
                app.SpeedEditField.Visible=0;
            end
            assignin('base','moviechckbox',value);
            evalin('base','projectStruct.Info.Flags.Plot.Movie=double(moviechckbox);');
        end

        % Value changed function: DataRangeMethodEditField
        function DataRangeMethodEditFieldValueChanged(app, event)
            value = app.DataRangeMethodEditField.Value;
            app.DataRangeMethod=value; % Leave as 0 to plot everything.
        end

        % Value changed function: FrameIntervalEditField
        function FrameIntervalEditFieldValueChanged(app, event)
            value = app.FrameIntervalEditField.Value;
            assignin('base','movieFrameInt',value);
            evalin('base','projectStruct.Info.Flags.Plot.FrameInterval=movieFrameInt;');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Run one plotting function
            cd(app.ProjectDataPathEditField.Value);
            if isequal(app.projectName(end),char(13))
                app.projectName=app.projectName(1:end-1);            
            end
            if isempty(app.plotFuncName) || exist(app.plotFuncName,'file')~=2
                disp('Improperly specified function name!');
                return;
            end
            if isempty(app.SubfolderName)
                disp('No subfolder specified!');
                return;
            end
            if exist(app.plotFuncName,'file')==2 && exist(['specifyTrials_' app.projectName '_' app.plotFuncName '.m'],'file')~=2
                disp('No specifyTrials for this function!');
                return;
            end
            assignin('base','plotFuncName',app.plotFuncName);
            evalin('base','plotFunc(1).Name=plotFuncName;');
            evalin('base','projectStruct.Info.Flags.Org=1;');
            assignin('base','dataRangeMethod',app.DataRangeMethod);
            evalin('base','projectStruct.Info.DataRangeMethod=dataRangeMethod;');
            dbstop if caught error;
            evalin('base','plotData_GUI(projectStruct,plotFunc);');
            dbclear if caught error;
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = app.SpeedEditField.Value;
            assignin('base','percSpeed',value);
            evalin('base','projectStruct.Info.Flags.Plot.PercSpeed=percSpeed;');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Tooltip = {''};
            app.ImportTab.Title = 'Import';
            app.LogsheetPathEditFieldLabel = uilabel(app.ImportTab);
            app.LogsheetPathEditFieldLabel.Text = 'Logsheet Path';
            app.LogsheetPathEditField = uieditfield(app.ImportTab, 'text');
            app.LogsheetPathEditField.ValueChangedFcn = createCallbackFcn(app, @LogsheetPathEditFieldValueChanged, true);
            app.LogsheetPathEditField.Tooltip = {'Full path to the logsheet for all subjects of this project. Should end in .xls or.xlsx.'};
            app.LogsheetPathEditField.Value = 'Full Path to Logsheet Excel File (ends in .xls)';
            app.ProjectNameEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectNameEditField.Tooltip = {'The project name. This name identifies the project attributes.'};
            app.ProjectNameEditField.Value = 'Project Name';
            app.ProjectDataPathEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectDataPathEditFieldLabel.Text = 'Project Data Path';
            app.ProjectDataPathEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectDataPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectDataPathEditFieldValueChanged, true);
            app.ProjectDataPathEditField.Tooltip = {'Full path to the folder containing ''Subject Data'' folder for this project.'};
            app.ProjectDataPathEditField.Value = 'Full Path to Project Data Folder (contains Subject Data)';
            app.RunButton = uibutton(app.ImportTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Import the data into Matlab, whether from C3D (first time) or reloading from MAT.'};
            app.RunButton.Text = 'Run';
            app.RedoCheckBox = uicheckbox(app.ImportTab);
            app.RedoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedoCheckBoxValueChanged, true);
            app.RedoCheckBox.Tooltip = {'WARNING: Overwrites all existing data!!'};
            app.RedoCheckBox.Text = 'Redo';
            app.AddDataTypesCheckBox = uicheckbox(app.ImportTab);
            app.AddDataTypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddDataTypesCheckBoxValueChanged, true);
            app.AddDataTypesCheckBox.Tooltip = {'Does not overwrite. Adds new data types to existing data sets (if any).'};
            app.AddDataTypesCheckBox.Text = 'Add Data Types';
            app.UpdateMetadataOnlyCheckBox = uicheckbox(app.ImportTab);
            app.UpdateMetadataOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateMetadataOnlyCheckBoxValueChanged, true);
            app.UpdateMetadataOnlyCheckBox.Tooltip = {'Updates (overwrites) metadata found in logsheet and import settings.'};
            app.UpdateMetadataOnlyCheckBox.Text = 'Update Metadata Only';
            app.ReloadExistingDataCheckBox = uicheckbox(app.ImportTab);
            app.ReloadExistingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReloadExistingDataCheckBoxValueChanged, true);
            app.ReloadExistingDataCheckBox.Tooltip = {'Does not overwrite. Reloads existing .mat file data if needed.'};
            app.ReloadExistingDataCheckBox.Text = 'Reload Existing Data';
            app.OpenimportSettingsButton = uibutton(app.ImportTab, 'push');
            app.OpenimportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimportSettingsButtonPushed, true);
            app.OpenimportSettingsButton.Tooltip = {'Open importSettings_ProjectName file'};
            app.OpenimportSettingsButton.Text = 'Open importSettings';
            app.OpenspecifyTrials_importProjectButton = uibutton(app.ImportTab, 'push');
            app.OpenspecifyTrials_importProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrials_importProjectButtonPushed, true);
            app.OpenspecifyTrials_importProjectButton.Tooltip = {'Open specifyTrials_ProjectName_importProject'};
            app.OpenspecifyTrials_importProjectButton.Text = 'Open specifyTrials_importProject';
            app.ProjectProcessingPathLabel = uilabel(app.ImportTab);
            app.ProjectProcessingPathLabel.Text = 'Project Processing Path';
            app.ProjectProcessingPathEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectProcessingPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectProcessingPathEditFieldValueChanged, true);
            app.ProjectProcessingPathEditField.Tooltip = {'Full path to folder with all processing functions for this project.'};
            app.ProjectProcessingPathEditField.Value = 'Full Path to Project Processing Functions Folder';
            app.DropDown2_4 = uidropdown(app.ImportTab);
            app.DropDown2_4.ValueChangedFcn = createCallbackFcn(app, @DropDown2_4ValueChanged, true);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.TabGroup2 = uitabgroup(app.ProcessTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = 'Group functions together based on what order they''re run and which set of trials they''re run on';
            app.FunctionNamesLabel = uilabel(app.SetupTab);
            app.FunctionNamesLabel.Text = 'Function Names';
            app.FunctionNamesTextArea = uitextarea(app.SetupTab);
            app.FunctionNamesTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionNamesTextAreaValueChanged, true);
            app.FunctionNamesTextArea.Tooltip = {'Enter the function names that are part of this group. Functions in a group operate on the same set of trials.'};
            app.FunctionGroupEditFieldLabel = uilabel(app.SetupTab);
            app.FunctionGroupEditFieldLabel.Text = 'Function Group #';
            app.FunctionGroupEditField = uieditfield(app.SetupTab, 'numeric');
            app.FunctionGroupEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionGroupEditFieldValueChanged, true);
            app.OpenspecifyTrialsButton = uibutton(app.SetupTab, 'push');
            app.OpenspecifyTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrialsButtonPushed, true);
            app.OpenspecifyTrialsButton.Tooltip = {'Open the specifyTrials_ProjectName_GroupNum file.'};
            app.OpenspecifyTrialsButton.Text = 'Open specifyTrials';
            app.AddFunctionstoGroupSavetoFileButton = uibutton(app.SetupTab, 'push');
            app.AddFunctionstoGroupSavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunctionstoGroupSavetoFileButtonPushed, true);
            app.AddFunctionstoGroupSavetoFileButton.Tooltip = {'Add the functions in the list to the current group and save to file. Clears args list in "Run" subtab (but not in file).'};
            app.AddFunctionstoGroupSavetoFileButton.Text = 'Add Functions to Group & Save to File';
            app.ClickoutsideboxwhendoneLabel = uilabel(app.SetupTab);
            app.ClickoutsideboxwhendoneLabel.Text = 'Click outside box when done.';
            app.LoadFunctionNamesinGroupFromFileButton = uibutton(app.SetupTab, 'push');
            app.LoadFunctionNamesinGroupFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFunctionNamesinGroupFromFileButtonPushed, true);
            app.LoadFunctionNamesinGroupFromFileButton.Tooltip = {'Load group function names from the file.'};
            app.LoadFunctionNamesinGroupFromFileButton.Text = 'Load Function Names in Group From File';
            app.NewProcessingFunctionTrialLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionTrialLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionTrialLevelButtonPushed, true);
            app.NewProcessingFunctionTrialLevelButton.Tooltip = {'Create a new trial level processing function from template.'};
            app.NewProcessingFunctionTrialLevelButton.Text = 'New Processing Function: Trial Level';
            app.NewProcessingFunctionSubjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionSubjectLevelButtonPushed, true);
            app.NewProcessingFunctionSubjectLevelButton.Tooltip = {'Create a new subject level processing function from template.'};
            app.NewProcessingFunctionSubjectLevelButton.Text = 'New Processing Function: Subject Level';
            app.NewProcessingFunctionProjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionProjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionProjectLevelButtonPushed, true);
            app.NewProcessingFunctionProjectLevelButton.Tooltip = {'Create a new project level processing function from template.'};
            app.NewProcessingFunctionProjectLevelButton.Text = 'New Processing Function: Project Level';
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.DropDownLabel = uilabel(app.RunTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RunTab);
            app.DropDown.Items = {'Group 1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'Which group is currently open.'};
            app.DropDown.Value = 'Group 1';
            app.RedoFcnLabel = uilabel(app.RunTab);
            app.RedoFcnLabel.Tooltip = {'Indicates whether to redo a function''s processing.'};
            app.RedoFcnLabel.Text = 'Redo Fcn';
            app.CheckBox_1 = uicheckbox(app.RunTab);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.RunGroupButton = uibutton(app.RunTab, 'push');
            app.RunGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RunGroupButtonPushed, true);
            app.RunGroupButton.Tooltip = {'Run all of the functions in the group.'};
            app.RunGroupButton.Text = 'Run Group';
            app.SaveArgstoFileButton = uibutton(app.RunTab, 'push');
            app.SaveArgstoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArgstoFileButtonPushed, true);
            app.SaveArgstoFileButton.Tooltip = {'Save the arguments to the project file.'};
            app.SaveArgstoFileButton.Text = 'Save Args to File';
            app.RunAllButton = uibutton(app.RunTab, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Tooltip = {'Run all functions in all groups.'};
            app.RunAllButton.Text = 'Run All';
            app.CheckBox_2 = uicheckbox(app.RunTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.RunTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.RunTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.RunTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.RunTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.RunTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.RunTab);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.RunTab);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.RunTab);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '10';
            app.FunctionNamesTextArea_2Label = uilabel(app.RunTab);
            app.FunctionNamesTextArea_2Label.Text = 'FunctionNames';
            app.FunctionNamesTextArea_2 = uitextarea(app.RunTab);
            app.FunctionNamesTextArea_2.Tooltip = {'Names of the functions in this group.'};
            app.FunctionArgsTextAreaLabel = uilabel(app.RunTab);
            app.FunctionArgsTextAreaLabel.Text = 'FunctionArgs';
            app.FunctionArgsTextArea = uitextarea(app.RunTab);
            app.FunctionArgsTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionArgsTextAreaValueChanged, true);
            app.FunctionArgsTextArea.Tooltip = {'The arguments for each function. If none, leave blank. If multiple, separate with comma and space. Enclose chars in single quotes.'};
            app.DropDown2 = uidropdown(app.RunTab);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.OpenFunctionButton = uibutton(app.RunTab, 'push');
            app.OpenFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFunctionButtonPushed, true);
            app.OpenFunctionButton.Text = 'Open Function';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.MultipleTrialsCheckBox = uicheckbox(app.PlotTab);
            app.MultipleTrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipleTrialsCheckBoxValueChanged, true);
            app.MultipleTrialsCheckBox.Tooltip = {'Plot all trials of each condition on its own plot. E.g. 3 conditions, 3 plots per subject. If unchecked, one plot per trial.'};
            app.MultipleTrialsCheckBox.Text = 'Multiple Trials';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'Run the plotting function.'};
            app.PlotButton.Text = 'Plot';
            app.SaveFigCheckBox = uicheckbox(app.PlotTab);
            app.SaveFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFigCheckBoxValueChanged, true);
            app.SaveFigCheckBox.Tooltip = {'Save plots as FIG'};
            app.SaveFigCheckBox.Text = 'Save Fig';
            app.SavePNGCheckBox = uicheckbox(app.PlotTab);
            app.SavePNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePNGCheckBoxValueChanged, true);
            app.SavePNGCheckBox.Tooltip = {'Save plots as PNG'};
            app.SavePNGCheckBox.Text = 'Save PNG';
            app.SavetransparentPNGCheckBox = uicheckbox(app.PlotTab);
            app.SavetransparentPNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavetransparentPNGCheckBoxValueChanged, true);
            app.SavetransparentPNGCheckBox.Tooltip = {'Save plots as transparent PNG. May affect image quality.'};
            app.SavetransparentPNGCheckBox.Text = 'Save transparent PNG';
            app.RootSaveFolderEditFieldLabel = uilabel(app.PlotTab);
            app.RootSaveFolderEditFieldLabel.Text = 'Root Save Folder';
            app.RootSaveFolderEditField = uieditfield(app.PlotTab, 'text');
            app.RootSaveFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RootSaveFolderEditFieldValueChanged, true);
            app.RootSaveFolderEditField.Tooltip = {'The full base folder path to save plots.'};
            app.RootSaveFolderEditField.Value = 'Root Folder Path';
            app.CurrentSaveSubfolderEditFieldLabel = uilabel(app.PlotTab);
            app.CurrentSaveSubfolderEditFieldLabel.Text = 'Current Save Subfolder';
            app.CurrentSaveSubfolderEditField = uieditfield(app.PlotTab, 'text');
            app.CurrentSaveSubfolderEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSaveSubfolderEditFieldValueChanged, true);
            app.CurrentSaveSubfolderEditField.Tooltip = {'Subfolder name to save plots within the root save folder.'};
            app.CurrentSaveSubfolderEditField.Value = 'Subfolder Name';
            app.SaveSVGCheckBox = uicheckbox(app.PlotTab);
            app.SaveSVGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveSVGCheckBoxValueChanged, true);
            app.SaveSVGCheckBox.Tooltip = {'Save plots as SVG.'};
            app.SaveSVGCheckBox.Text = 'Save SVG';
            app.PlottingFunctionNameEditFieldLabel = uilabel(app.PlotTab);
            app.PlottingFunctionNameEditFieldLabel.Text = 'Plotting Function Name';
            app.PlottingFunctionNameEditField = uieditfield(app.PlotTab, 'text');
            app.PlottingFunctionNameEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingFunctionNameEditFieldValueChanged, true);
            app.PlottingFunctionNameEditField.Tooltip = {'Name of the plotting function to run.'};
            app.PlottingFunctionNameEditField.Value = 'Plotting Function Name';
            app.NewStaticPlottingFunctionSubjectLevelButton = uibutton(app.PlotTab, 'push');
            app.NewStaticPlottingFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewStaticPlottingFunctionSubjectLevelButtonPushed, true);
            app.NewStaticPlottingFunctionSubjectLevelButton.Tooltip = {'Create new subject level plotting function from template.'};
            app.NewStaticPlottingFunctionSubjectLevelButton.Text = 'New Static Plotting Function - Subject Level';
            app.NewStaticPlottingFunctionProjectLevelButton = uibutton(app.PlotTab, 'push');
            app.NewStaticPlottingFunctionProjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewStaticPlottingFunctionProjectLevelButtonPushed, true);
            app.NewStaticPlottingFunctionProjectLevelButton.Tooltip = {'Create new project level plotting function from template.'};
            app.NewStaticPlottingFunctionProjectLevelButton.Text = 'New Static Plotting Function - Project Level';
            app.XSubsetDropDown = uidropdown(app.PlotTab);
            app.XSubsetDropDown.Items = {'All', 'Subject', 'Condition', 'SubjCond', 'Each'};
            app.XSubsetDropDown.ValueChangedFcn = createCallbackFcn(app, @XSubsetDropDownValueChanged, true);
            app.XSubsetDropDown.Tooltip = {'Indicate which subset of the data should be used to generate X axis limits.'};
            app.XSubsetDropDown.Value = 'All';
            app.DropDown2_2 = uidropdown(app.PlotTab);
            app.DropDown2_2.Items = {'All', 'Subject', 'Condition', 'SubjCond', 'Each'};
            app.DropDown2_2.ValueChangedFcn = createCallbackFcn(app, @YSubsetDropDownValueChanged, true);
            app.DropDown2_2.Tooltip = {'Indicate which subset of the data should be used to generate Y axis limits.'};
            app.DropDown2_2.Value = 'All';
            app.DropDown2_3 = uidropdown(app.PlotTab);
            app.DropDown2_3.Items = {'All', 'Subject', 'Condition', 'SubjCond', 'Each'};
            app.DropDown2_3.ValueChangedFcn = createCallbackFcn(app, @ZSubsetDropDownValueChanged, true);
            app.DropDown2_3.Tooltip = {'(If Z data) Indicate which subset of the data should be used to generate Z axis limits.'};
            app.DropDown2_3.Value = 'All';
            app.FrameNumbersCheckBox = uicheckbox(app.PlotTab);
            app.FrameNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrameNumbersCheckBoxValueChanged, true);
            app.FrameNumbersCheckBox.Tooltip = {'Unchecked=Timeseries X axis in seconds.'; 'Checked=Timeseries X axis in frame numbers.'};
            app.FrameNumbersCheckBox.Text = 'Frame Numbers';
            app.SavingPlotToEditFieldLabel = uilabel(app.PlotTab);
            app.SavingPlotToEditFieldLabel.Text = 'Saving Plot To:';
            app.SavingPlotToEditField = uieditfield(app.PlotTab, 'text');
            app.SavingPlotToEditField.Editable = 'off';
            app.SavingPlotToEditField.Tooltip = {'Location to save the plots. Within this folder they will be split by Subject name & date, and figure format.'};
            app.OpenspecifyTrialsButton_2 = uibutton(app.PlotTab, 'push');
            app.OpenspecifyTrialsButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrialsButton_2Pushed, true);
            app.OpenspecifyTrialsButton_2.Tooltip = {'Open specifyTrials_ProjectName_PlotFuncName file.'};
            app.OpenspecifyTrialsButton_2.Text = 'Open specifyTrials';
            app.XVarSubsetLabel = uilabel(app.PlotTab);
            app.XVarSubsetLabel.Tooltip = {'Indicate which subset of the data should be used to generate X axis limits.'};
            app.XVarSubsetLabel.Text = 'X Var Subset';
            app.YVarSubsetLabel = uilabel(app.PlotTab);
            app.YVarSubsetLabel.Tooltip = {'Indicate which subset of the data should be used to generate Y axis limits.'};
            app.YVarSubsetLabel.Text = 'Y Var Subset';
            app.ZVarSubsetLabel = uilabel(app.PlotTab);
            app.ZVarSubsetLabel.Tooltip = {'(If Z data) Indicate which subset of the data should be used to generate Z axis limits.'};
            app.ZVarSubsetLabel.Text = 'Z Var Subset';
            app.FrameIntervalEditFieldLabel = uilabel(app.PlotTab);
            app.FrameIntervalEditFieldLabel.Text = 'Frame Interval';
            app.FrameIntervalEditField = uieditfield(app.PlotTab, 'numeric');
            app.FrameIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @FrameIntervalEditFieldValueChanged, true);
            app.FrameIntervalEditField.Tooltip = {'Number of frames to increment in each frame of the movie. Set to 1 to see every frame.'};
            app.SpeedEditFieldLabel = uilabel(app.PlotTab);
            app.SpeedEditFieldLabel.Text = '% Speed';
            app.SpeedEditField = uieditfield(app.PlotTab, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.SpeedEditField.Tooltip = {'Playback speed relative to full speed. 0-1, 1=full speed.'};
            app.MovieCheckBox = uicheckbox(app.PlotTab);
            app.MovieCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox.Text = 'Movie';
            app.DataRangeMethodEditFieldLabel = uilabel(app.PlotTab);
            app.DataRangeMethodEditFieldLabel.Text = 'Data Range Method';
            app.DataRangeMethodEditField = uieditfield(app.PlotTab, 'numeric');
            app.DataRangeMethodEditField.ValueChangedFcn = createCallbackFcn(app, @DataRangeMethodEditFieldValueChanged, true);
            app.CheckBox_11 = uicheckbox(app.PlotTab);
            app.NewMovieFunctionSubjectLevelButton = uibutton(app.PlotTab, 'push');
            app.NewMovieFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewMovieFunctionSubjectLevelButtonPushed, true);
            app.NewMovieFunctionSubjectLevelButton.Text = 'New Movie Function - Subject Level';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessingAppV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end